classdef longitudinal_stability_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        wing                            matlab.ui.container.Tab
        cm_alpha_w_field_w              matlab.ui.control.NumericEditField
        Alphacl0Label_10                matlab.ui.control.Label
        cm_0_w_field_w                  matlab.ui.control.NumericEditField
        Alphacl0Label_9                 matlab.ui.control.Label
        static_stability_label_w        matlab.ui.control.Label
        x_ac_c_field_w                  matlab.ui.control.NumericEditField
        Alphacl0Label_8                 matlab.ui.control.Label
        x_cg_c_field_w                  matlab.ui.control.NumericEditField
        Alphacl0Label_7                 matlab.ui.control.Label
        cg_and_ac_locations_label_w     matlab.ui.control.Label
        NACA4SeriesAirfoilEditFieldLabel  matlab.ui.control.Label
        finite_wing_correction_label_w  matlab.ui.control.Label
        wing_airfoil_data_label_w       matlab.ui.control.Label
        naca_4_field_w                  matlab.ui.control.EditField
        cL_alpha_w_field_w              matlab.ui.control.NumericEditField
        Alphacl0Label_6                 matlab.ui.control.Label
        span_efficiency_field_w         matlab.ui.control.NumericEditField
        Alphacl0Label_5                 matlab.ui.control.Label
        aspect_ratio_field_w            matlab.ui.control.NumericEditField
        Alphacl0Label_4                 matlab.ui.control.Label
        alpha_cl0_foil_field_w          matlab.ui.control.NumericEditField
        cl_alpha_foil_field_w           matlab.ui.control.NumericEditField
        Alphacl0Label_2                 matlab.ui.control.Label
        cm_ac_w_field_w                 matlab.ui.control.NumericEditField
        Alphacl0Label_3                 matlab.ui.control.Label
        Alphacl0Label                   matlab.ui.control.Label
        stability_plot_w                matlab.ui.control.UIAxes
        tail                            matlab.ui.container.Tab
        wing_area_field_t               matlab.ui.control.NumericEditField
        ltLabel_10                      matlab.ui.control.Label
        naca4_edit_field_t              matlab.ui.control.EditField
        NACA4SeriesAirfoilEditFieldLabel_2  matlab.ui.control.Label
        cm_alpha_t_field_t              matlab.ui.control.NumericEditField
        ltLabel_9                       matlab.ui.control.Label
        cm_0_t_field_t                  matlab.ui.control.NumericEditField
        ltLabel_8                       matlab.ui.control.Label
        static_stability_filed_t        matlab.ui.control.Label
        incident_tail_field_t           matlab.ui.control.NumericEditField
        ltLabel_7                       matlab.ui.control.Label
        incident_wing_angle_field_t     matlab.ui.control.NumericEditField
        ltLabel_6                       matlab.ui.control.Label
        tail_efficiency_field_t         matlab.ui.control.NumericEditField
        ltLabel_5                       matlab.ui.control.Label
        other_inputs_label_t            matlab.ui.control.Label
        v_h_field_t                     matlab.ui.control.NumericEditField
        ltLabel_4                       matlab.ui.control.Label
        wing_mean_cord_field_t          matlab.ui.control.NumericEditField
        ltLabel_3                       matlab.ui.control.Label
        tail_area_field_t               matlab.ui.control.NumericEditField
        ltLabel_2                       matlab.ui.control.Label
        l_t_field_t                     matlab.ui.control.NumericEditField
        ltLabel                         matlab.ui.control.Label
        finite_wing_correction_label_t_2  matlab.ui.control.Label
        cL_alpha_t_field_t              matlab.ui.control.NumericEditField
        cL_alpha_tEditFieldLabel        matlab.ui.control.Label
        span_efficiency_field_t         matlab.ui.control.NumericEditField
        SpanEfficiencyEditFieldLabel    matlab.ui.control.Label
        aspect_ratio_field_t            matlab.ui.control.NumericEditField
        AspectRatiotailEditFieldLabel   matlab.ui.control.Label
        finite_wing_correction_label_t  matlab.ui.control.Label
        cm_ac_t_field_t                 matlab.ui.control.NumericEditField
        Alphacl0Label_13                matlab.ui.control.Label
        cl_alpha_foil_field_t           matlab.ui.control.NumericEditField
        Alphacl0Label_12                matlab.ui.control.Label
        alpha_cl0_foil_field_t          matlab.ui.control.NumericEditField
        Alphacl0Label_11                matlab.ui.control.Label
        tail_airfoil_data_label_t       matlab.ui.control.Label
        fuselage                        matlab.ui.container.Tab
        results_panel                   matlab.ui.container.Panel
        cm_alpha_edit_field_r           matlab.ui.control.NumericEditField
        cm_alpha1radsLabel              matlab.ui.control.Label
        cm_0_edit_field_r               matlab.ui.control.NumericEditField
        cM_alpha1radsLabel              matlab.ui.control.Label
        cm_alpha_t_edit_field_r         matlab.ui.control.NumericEditField
        cm_alpha_t1radsEditFieldLabel   matlab.ui.control.Label
        cm_0_t_edit_field_r             matlab.ui.control.NumericEditField
        cm_0_tEditFieldLabel            matlab.ui.control.Label
        cm_alpha_w_edit_field_r         matlab.ui.control.NumericEditField
        cm_alpha_w1radsEditFieldLabel   matlab.ui.control.Label
        cm_0_w_edit_field_r             matlab.ui.control.NumericEditField
        cm_0_wEditFieldLabel            matlab.ui.control.Label
        cm_alpha_f_edit_field_r         matlab.ui.control.NumericEditField
        cm_alpha_f1radsEditFieldLabel   matlab.ui.control.Label
        cm_0_f_edit_field_r             matlab.ui.control.NumericEditField
        cm_0_fEditFieldLabel            matlab.ui.control.Label
        stability_lamp_r                matlab.ui.control.Lamp
        LongitudinalStaticStabilityLampLabel  matlab.ui.control.Label
        fuselage_geometry_definition_f  matlab.ui.container.Panel
        wing_area_edit_field_f          matlab.ui.control.NumericEditField
        WingAreaSLabel                  matlab.ui.control.Label
        a0w_edit_field_f                matlab.ui.control.NumericEditField
        a0wradLabel                     matlab.ui.control.Label
        mean_cord_edit_field_f          matlab.ui.control.NumericEditField
        MeanCordEditFieldLabel          matlab.ui.control.Label
        fuselage_length_edit_field_f    matlab.ui.control.NumericEditField
        FuselageLengthEditFieldLabel    matlab.ui.control.Label
        fuselage_geometry_editor_f      matlab.ui.container.Panel
        wing_box_checkbox_f             matlab.ui.control.CheckBox
        if_checkbox_f                   matlab.ui.control.CheckBox
        height_slider_f                 matlab.ui.control.Slider
        XLabel_4                        matlab.ui.control.Label
        width_slider_f                  matlab.ui.control.Slider
        XLabel_3                        matlab.ui.control.Label
        y_xz_slider_f                   matlab.ui.control.Slider
        NoseYLabel_2                    matlab.ui.control.Label
        x_xz_slider_f                   matlab.ui.control.Slider
        XLabel_2                        matlab.ui.control.Label
        directions_f                    matlab.ui.control.TextArea
        y_xy_slider_f                   matlab.ui.control.Slider
        NoseYLabel                      matlab.ui.control.Label
        x_xy_slider_f                   matlab.ui.control.Slider
        XLabel                          matlab.ui.control.Label
        element_drop_down_f             matlab.ui.control.DropDown
        three_view_container            matlab.ui.container.Panel
        three_view_plot                 matlab.ui.control.UIAxes
        filepath_field_f                matlab.ui.control.EditField
        StepOneSpecifya3ViewImageFilepathjpgrecommendedLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        three_view_image;
        three_view_size = [0, 0];        
              
        elements_f = struct('name', {}, 'handles', {}, 'dimensions', {}, 'is_selected', {});  
        num_subdivs_f = 0;
    end
    
    methods (Access = private)
        
        function compute_from_naca_4_w(app) 

            [alpha_cl0, cl_alpha, cm_ac, did_error] = compute_naca_4(app, app.naca_4_field_w.Value);

            if did_error
                return
            end

            app.alpha_cl0_foil_field_w.Value = alpha_cl0;
            app.cl_alpha_foil_field_w.Value = cl_alpha;
            app.cm_ac_w_field_w.Value = cm_ac;

            % Propagate the changes forward
            compute_from_finite_wing_correction_w(app);

        end

        function compute_from_naca_4_t(app) 

            [alpha_cl0, cl_alpha, cm_ac, did_error] = compute_naca_4(app, app.naca4_edit_field_t.Value);

            if did_error
                return
            end

            app.alpha_cl0_foil_field_t.Value = alpha_cl0;
            app.cl_alpha_foil_field_t.Value = cl_alpha;
            app.cm_ac_t_field_t.Value = cm_ac;

            % Propagate the changes forward
            compute_from_finite_wing_correction_t(app);
        end
        
        function compute_from_finite_wing_correction_w(app)
            cl_alpha = app.cl_alpha_foil_field_w.Value;
            AR = app.aspect_ratio_field_w.Value;
            e = app.span_efficiency_field_w.Value;

            app.cL_alpha_w_field_w.Value = cl_alpha / (1 + (cl_alpha / (pi * e * AR)));

            % Propagate the changes forward
            compute_from_wing_static_stability(app);
        end

        function compute_from_finite_wing_correction_t(app)
            cl_alpha = app.cl_alpha_foil_field_t.Value;
            AR = app.aspect_ratio_field_t.Value;
            e = app.span_efficiency_field_t.Value;

            app.cL_alpha_t_field_t.Value = cl_alpha / (1 + (cl_alpha / (pi * e * AR)));

            % Propagate the changes forward
            compute_from_volume_ratio_t(app);
        end
        
        function compute_from_wing_static_stability(app)
            x_cg_c = app.x_cg_c_field_w.Value;
            x_ac_c = app.x_ac_c_field_w.Value;

            cm_ac_w = app.cm_ac_w_field_w.Value;
            alpha_cl_0_w = app.alpha_cl0_foil_field_w.Value;
            cL_alpha_w = app.cL_alpha_w_field_w.Value;
            cL_0_w = (-alpha_cl_0_w) * cL_alpha_w;

            cm_0_w = cm_ac_w + cL_0_w * (x_cg_c - x_ac_c);
            cm_alpha_w = cL_alpha_w *  (x_cg_c - x_ac_c);

            app.cm_0_w_field_w.Value = cm_0_w;
            app.cm_0_w_edit_field_r.Value = cm_0_w;
            app.cm_alpha_w_field_w.Value = cm_alpha_w;
            app.cm_alpha_w_edit_field_r.Value = cm_alpha_w;

            % Update stability plot
            alpha_range = linspace(-15, 15, 2); % Angle of attack range in degrees
            cm_values = cm_0_w + (cm_alpha_w * deg2rad(alpha_range)); % Moment coefficient
        
            % Plot the stability line
            plot(app.stability_plot_w, alpha_range, cm_values, 'b-');
            xlabel(app.stability_plot_w, 'Angle of Attack (degrees)');
            ylabel(app.stability_plot_w, 'Moment Coefficient (Cm)');
            title(app.stability_plot_w, 'Wing Longitudinal Static Stability Plot');
            grid(app.stability_plot_w, 'on');

            xlim(app.stability_plot_w, [min(alpha_range) max(alpha_range)]);
            ylim(app.stability_plot_w, [min(cm_values) max(cm_values)]);
            
            compute_from_tail_static_stability(app);
        end
        
        function refresh_three_view_plot_f(app) 
            
            for i = 1:length(app.elements_f)
                if ~app.elements_f(i).is_selected
                    set(app.elements_f(i).handles(1), 'Color', 'c')
                    set(app.elements_f(i).handles(2), 'Color', 'c')
                    
                    if length(app.elements_f(i).handles) == 3
                        if strcmp('Nose Point', app.elements_f(i).name) || strcmp('End Point', app.elements_f(i).name)
                            set(app.elements_f(i).handles(3), 'Color', 'c')
                            [x_points, y_points] = compute_xz_ref_line_end_points_f(app, app.elements_f(i));
                            set(app.elements_f(i).handles(3), 'XData', x_points, 'YData', y_points);                                        
                        else
                            % Subdivision
                            set(app.elements_f(i).handles(3), 'Color', 'c')
                            [x_points, y_points] = compute_xy_subdiv_line_end_points_f(app, app.elements_f(i));
                            set(app.elements_f(i).handles(3), 'XData', x_points, 'YData', y_points);
                        end
                    end
                else
                    
                    set(app.elements_f(i).handles(1), 'Color', 'm')
                    set(app.elements_f(i).handles(1), 'XData', app.elements_f(i).dimensions(1, 1), 'YData', app.elements_f(i).dimensions(1, 2));
    
                    set(app.elements_f(i).handles(2), 'Color', 'r')
                    set(app.elements_f(i).handles(2), 'XData', app.elements_f(i).dimensions(2, 1), 'YData', app.elements_f(i).dimensions(2, 2));
    
                    if length(app.elements_f(i).handles) == 3

                        if strcmp('Nose Point', app.elements_f(i).name) || strcmp('End Point', app.elements_f(i).name)
                            set(app.elements_f(i).handles(3), 'Color', 'g')
                            [x_points, y_points] = compute_xz_ref_line_end_points_f(app, app.elements_f(i));
                            set(app.elements_f(i).handles(3), 'XData', x_points, 'YData', y_points);                                        
                        else
                            % Subdivision
                            set(app.elements_f(i).handles(3), 'Color', 'g')
                            [x_points, y_points] = compute_xy_subdiv_line_end_points_f(app, app.elements_f(i));
                            set(app.elements_f(i).handles(3), 'XData', x_points, 'YData', y_points);
                        end
                    end                   
                end
            end
        end
        
        function load_three_view_image_f(app, path)
            temp = app.three_view_image;
            try
                app.three_view_image = imread(path);
            catch
                app.three_view_image = temp;
                return
            end
            title(app.three_view_plot, path);
            init_three_view_plot_f(app);
        end
        
        function init_three_view_plot_f(app)

            imshow(app.three_view_image, 'Parent', app.three_view_plot);
        
            hold(app.three_view_plot, 'on');
            
            app.three_view_size = [size(app.three_view_image, 2), size(app.three_view_image, 1), max(size(app.three_view_image, 2), size(app.three_view_image, 1))];

            create_elements_f(app);

            axis(app.three_view_plot, [0 size(app.three_view_image, 2) 0 size(app.three_view_image, 1)]);

            hold(app.three_view_plot, 'off');
        end
        
        function create_elements_f(app)      
            
            % Reset Elements
            app.elements_f = struct('name', {}, 'handles', {}, 'dimensions', {}, 'is_selected', {});
            app.num_subdivs_f = 0;

            % Nose
            nose_xy_handle_f = plot(app.three_view_plot, [0,0], 'om');
            nose_xz_handle_f = plot(app.three_view_plot, [0,0], 'or');    
            nose_xz_ref_line_handle_f = plot(app.three_view_plot, [0, 0], [0, 0], 'c');
            
            add_element_f(app, 'Nose Point', [nose_xy_handle_f, nose_xz_handle_f,nose_xz_ref_line_handle_f], [0,0;0,0;0,0], true, false, false); % Set nose to selected by default

            for i = 1:10
                d_xy_handle_f = plot(app.three_view_plot, [0,0], 'oc');
                d_xz_handle_f = plot(app.three_view_plot, [0,0], 'oc');

                d_xy_width_line = plot(app.three_view_plot, [0, 0], [0, 0], 'c');

                name = ['D', num2str(i)];

                add_element_f(app, name, [d_xy_handle_f, d_xz_handle_f, d_xy_width_line], [0,0;0,0;0,0], false, false, false);
            end

            % End
            end_xy_handle_f = plot(app.three_view_plot, [0,0], 'oc');
            end_xz_handle_f = plot(app.three_view_plot, [0,0], 'oc');
            end_xz_ref_line_handle_f = plot(app.three_view_plot, [0, 0], [0, 0], 'c');
            
            add_element_f(app, 'End Point', [end_xy_handle_f, end_xz_handle_f, end_xz_ref_line_handle_f], [0,0;0,0;0,0], false, false, false);

            % Reset element drop down menu     
            app.element_drop_down_f.Items = {'Nose Point', 'D1', 'D2', 'D3', 'D4', 'D5', 'D6', 'D7', 'D8', 'D9', 'D10', 'End Point'} ;
            app.element_drop_down_f.Value = 'Nose Point'; % Set nose to selected by default   
            app.width_slider_f.Enable = false;
            app.height_slider_f.Enable = true;
        end
        
        function add_element_f(app, name, handles, dimensions, is_selected, is_wing_box, has_positive_incidence)     
            index = length(app.elements_f) + 1;  % Update to add new element
            app.elements_f(index).name = name;
            app.elements_f(index).handles = handles;
            app.elements_f(index).dimensions = dimensions;
            app.elements_f(index).is_selected = is_selected; 
            app.elements_f(index).is_wing_box = is_wing_box;
            app.elements_f(index).has_positive_incidence = has_positive_incidence;
        end
        
        
        function refresh_sliders_f(app, element)
            app.x_xy_slider_f.Value = element.dimensions(1,1) / app.three_view_size(1);
            app.y_xy_slider_f.Value = element.dimensions(1,2) / app.three_view_size(2);
            app.x_xz_slider_f.Value = element.dimensions(2,1) / app.three_view_size(1);
            app.y_xz_slider_f.Value = element.dimensions(2,2) / app.three_view_size(2);
            app.width_slider_f.Value = element.dimensions(3,1) / app.three_view_size(3);
            app.height_slider_f.Value = element.dimensions(3,2) / app.three_view_size(3);             
        end
        
        function update_element_dimensions_f(app, x, y, v, d)
            for i = 1:length(app.elements_f)
                if app.elements_f(i).is_selected                   
                    app.elements_f(i).dimensions(x,y) = v * app.three_view_size(d);
                    refresh_three_view_plot_f(app);
                    compute_longitudinal_static_stability_f(app);
                    return
                end
            end
        end
        
        function [x_end_points, y_end_points] = compute_xy_subdiv_line_end_points_f(app, subdiv)
            nose_f = get_element_f(app, 'Nose Point');
            end_f = get_element_f(app, 'End Point');
            xy_centerline_f = [end_f.dimensions(1,1) - nose_f.dimensions(1,1), end_f.dimensions(1,2) - nose_f.dimensions(1,2)];
            
            if norm(xy_centerline_f) == 0
                x_end_points = [0,0];
                y_end_points = [0,0];
                return
            end

            subdiv_vector_f = [-xy_centerline_f(2), xy_centerline_f(1)] ./ norm(xy_centerline_f);
            
            points = [subdiv.dimensions(1, :) + (subdiv_vector_f .* subdiv.dimensions(3,1) .* 0.5); subdiv.dimensions(1, :) - (subdiv_vector_f .* subdiv.dimensions(3,1) .* 0.5)];    
            
            x_end_points = points(:,1)';
            y_end_points = points(:,2)';

        end
        
        function element_f = get_element_f(app, name)
            for i = 1:length(app.elements_f)               
                if strcmp(app.elements_f(i).name, name)
                    element_f = app.elements_f(i);
                    return
                end
            end
        end
        
        function [x_end_points, y_end_points] = compute_xz_ref_line_end_points_f(app, point_element)
            nose_f = get_element_f(app, 'Nose Point');
            end_f = get_element_f(app, 'End Point');
            xz_centerline = [end_f.dimensions(2,1) - nose_f.dimensions(2,1), end_f.dimensions(2,2) - nose_f.dimensions(2,2)];
            
            if norm(xz_centerline) == 0
                x_end_points = [0,0];
                y_end_points = [0,0];
                return
            end
            
            normal_vector_f = [-xz_centerline(2), xz_centerline(1)] ./ norm(xz_centerline);

            points = [point_element.dimensions(2, :); point_element.dimensions(2, :) + (normal_vector_f .* point_element.dimensions(3,2))];    
            
            x_end_points = points(:,1)';
            y_end_points = points(:,2)';
        end
        
        function k2k1 = compute_k2k1_f(~, body_fineness)
            if body_fineness < 0
                k2k1 = 0.6;
                return
            end
        
            data = [0,0.6; 4, 0.77; 5, 0.81; 7, 0.88; 10, 0.94; 14, 0.945; 20, 0.95];
        
            fineness_values = data(:, 1);
            k2k1_values = data(:, 2);
        
            sqrt_fineness = sqrt(fineness_values);
            sqrt_body_fineness = sqrt(body_fineness);
        
            p = polyfit(sqrt_fineness, k2k1_values, 1);
        
            k2k1_linear = polyval(p, sqrt_body_fineness);
        
            k2k1 = min(0.95, k2k1_linear);
        end
        
        function deda = compute_deda_f(~, xi, use_line_a)
            if ~use_line_a
                if xi > 2
                    deda = 1.0625;
                    return     
                end
        
                % Interpolate using line A
                data = [0.2, 1.6; 0.5, 1.4; 1, 1.25; 1.5, 1.125; 2, 1.0625];
                p = polyfit(data(:, 1), data(:, 2), 2);
                deda = polyval(p, xi);
                
            else
                if xi > 1
                    deda = 1.8;
                    return     
                end
        
                % Interpolate using line B
                data = [0.2, 4; 0.25, 3.25; 0.5, 2.4; 0.75, 2; 1, 1.8];
                p = polyfit(data(:, 1), data(:, 2), 2);
                deda = polyval(p, xi);    
            end
        end

        
        function compute_longitudinal_static_stability_f(app)

            if app.fuselage_length_edit_field_f.Value == 0 || app.mean_cord_edit_field_f.Value == 0 || app.wing_area_edit_field_f.Value == 0
                return
            end

            % ---- compute cm_0_f ----
            
            % compute body fineness 
            values = zeros(length(app.elements_f), 1);
            for i = 1:length(app.elements_f)
                values(i) = app.elements_f(i).dimensions(3, 1);
            end
            max_width_px_f = max(values);
            [length_px] = get_fuselage_length_f(app);

            if length_px <= 0 || max_width_px_f <= 0
                return
            end

            body_fineness = length_px / max_width_px_f;

            % compute k2k1
            k2k1 = compute_k2k1_f(app, body_fineness);
            
            % find the xz ref line
            ref_vec_raw = compute_xz_ref_vec_f(app);
            ref_vec = ref_vec_raw / norm(ref_vec_raw);
            
            % compute if_table
            if_table = zeros(length(app.elements_f) - 1, 2);
            a0wif_table = zeros(length(app.elements_f) - 1, 2);
            
            a0w = app.a0w_edit_field_f.Value;
            a0w_deg = rad2deg(a0w);
            
            for i = 1:(length(app.elements_f)-1)
                vec = [app.elements_f(i+1).dimensions(2,1) - app.elements_f(i).dimensions(2,1), app.elements_f(i+1).dimensions(2,2) - app.elements_f(i).dimensions(2,2)];
                vec = vec / norm(vec);

                sign = -1;
                if app.elements_f(i+1).has_positive_incidence
                    sign = 1;
                end

                if_table(i, 1) = sign * abs(acos(dot(vec, ref_vec)));
                if_table(i, 2) = sign * abs(rad2deg(acos(dot(vec, ref_vec))));
                a0wif_table(i, 1) = if_table(i, 1) + a0w;
                a0wif_table(i, 2) = if_table(i, 2) + a0w_deg;
            end 
            
            unit_conversion = app.fuselage_length_edit_field_f.Value / length_px;

            % compute wf_table and delta_x table
            wf_table = zeros(length(app.elements_f) - 1, 1);
            delta_x_table = zeros(length(app.elements_f) - 1, 1);

            for i = 1:(length(app.elements_f)-1)
                wf_px = (app.elements_f(i).dimensions(3,1) + app.elements_f(i+1).dimensions(3,1)) / 2;
                wf_table(i) = wf_px * unit_conversion;
                
                vec = [app.elements_f(i+1).dimensions(2,1) - app.elements_f(i).dimensions(2,1), app.elements_f(i+1).dimensions(2,2) - app.elements_f(i).dimensions(2,2)];
                
                projection = (dot(ref_vec_raw, vec) / dot(ref_vec_raw, ref_vec_raw)) * ref_vec_raw; % changed to raw

                delta_x_px =  norm(projection);
                delta_x_table(i) = delta_x_px * unit_conversion;
                
            end
            
           
            sum_table = (wf_table .^2) .* a0wif_table(:, 2) .* delta_x_table;
                
            %disp([wf_table, delta_x_table, if_table, a0wif_table, sum_table]);

            S = app.wing_area_edit_field_f.Value;
            mean_cord = app.mean_cord_edit_field_f.Value;
            
            cm_0_f = (k2k1 / (36.5 * S * mean_cord)) * sum(sum_table);

            if isnan(cm_0_f)
                return
            end

            %disp(cm_0_f)

            % compute delta_x table
            xy_ref_vec = compute_xy_ref_vec_f(app);

            delta_x_table = zeros(length(app.elements_f) - 1, 1);

            for i = 1:(length(app.elements_f)-1)
                vec = [app.elements_f(i+1).dimensions(1,1) - app.elements_f(i).dimensions(1,1), app.elements_f(i+1).dimensions(1,2) - app.elements_f(i).dimensions(1,2)];
                
                projection = (dot(xy_ref_vec, vec) / dot(xy_ref_vec, xy_ref_vec)) * xy_ref_vec;

                delta_x_px =  norm(projection);
                delta_x_table(i) = delta_x_px * unit_conversion;
            end
            
            % Find the leading edge
            wing_leading_edge_x = -1;
            wing_leading_edge_idx = -1;
            dist = 0;
            for i = 1:(length(app.elements_f) - 1)
                if app.elements_f(i).is_wing_box
                    wing_leading_edge_x = dist;
                    wing_leading_edge_idx = i;
                    break
                end
                dist = dist + delta_x_table(i);
            end

            if wing_leading_edge_x == -1 || wing_leading_edge_idx == -1
                return
            end

            % Find the trailing edge
            ih = -1;
            wing_trailing_edge_idx = -1;
            dist = 0;
            for i = length(app.elements_f):-1:2
                if app.elements_f(i).is_wing_box
                    ih = dist;
                    wing_trailing_edge_idx = i;
                    break
                end
                dist = dist + delta_x_table(i - 1);
            end

            if ih == -1 || wing_trailing_edge_idx == -1
                return
            end

            %disp(wing_leading_edge_x)
            %disp(wing_leading_edge_idx)
            %disp(ih)
            %disp(wing_trailing_edge_idx)
            %disp("-----------")

            C = (length_px * unit_conversion) - wing_leading_edge_x - ih;

            % compute the deda table
            xi_table = zeros(length(app.elements_f) - 1, 1);
            deda_table = zeros(length(app.elements_f) - 1, 1);

            dist = 0;
            for i = 1:(wing_leading_edge_idx - 1)
                xi = wing_leading_edge_x - (dist + (delta_x_table(i) / 2));
                deda_table(i) = compute_deda_f(app, xi / C, true);
                xi_table(i) = xi;
                dist = dist + delta_x_table(i);
            end

            if (app.cL_alpha_w_field_w.Value == 0 || app.aspect_ratio_field_w.Value == 0)
                return
            end

            % use deg2rad to convert from 1/rad to 1/deg
            deda = (2 * deg2rad(app.cL_alpha_w_field_w.Value)) / (pi * app.aspect_ratio_field_w.Value);

            dist = 0;
            for i = wing_trailing_edge_idx : length(app.elements_f) - 1
                xi = (dist + (delta_x_table(i) / 2));
                deda_table(i) = (xi / ih) * (1 - deda);
                xi_table(i) = xi;
                dist = dist + delta_x_table(i);
            end

            sum_table_2 = wf_table.^2 .* deda_table .* delta_x_table;

            %disp([delta_x_table, wf_table, xi_table, deda_table, sum_table_2]);

            cm_alpha_f = (1 / (36.5 * S * mean_cord)) * sum(sum_table_2);
            %cm_alpha_f = rad2deg(cm_alpha_f); % Convert from 1/deg to 1/rad
            %disp(cm_alpha_f);

            % update results
            app.cm_0_f_edit_field_r.Value = cm_0_f;
            app.cm_alpha_f_edit_field_r.Value = cm_alpha_f;

            app.cm_0_edit_field_r.Value = app.cm_0_w_edit_field_r.Value + app.cm_0_t_edit_field_r.Value + cm_0_f;
            app.cm_alpha_edit_field_r.Value = app.cm_alpha_w_edit_field_r.Value + app.cm_alpha_t_edit_field_r.Value + cm_alpha_f;

            if app.cm_0_edit_field_r.Value > 0 && app.cm_alpha_edit_field_r.Value < 0
                app.stability_lamp_r.Color = 'green';
            else
                app.stability_lamp_r.Color = 'red';
            end
        end
        
        function [length_px] = get_fuselage_length_f(app)
            nose_f = get_element_f(app, 'Nose Point');
            end_f = get_element_f(app, 'End Point');
            length_px = sqrt((end_f.dimensions(1,1) - nose_f.dimensions(1,1))^2 + (end_f.dimensions(1,2) - nose_f.dimensions(1,2))^2);         
        end
        
        function xz_ref_vec = compute_xz_ref_vec_f(app)
            nose_f = get_element_f(app, 'Nose Point');
            end_f = get_element_f(app, 'End Point');
            xz_centerline = [end_f.dimensions(2,1) - nose_f.dimensions(2,1), end_f.dimensions(2,2) - nose_f.dimensions(2,2)];
            
            if norm(xz_centerline) == 0   
                xz_ref_vec = [0,0];
                return
            end
            
            normal_vec = [-xz_centerline(2), xz_centerline(1)] ./ norm(xz_centerline);

            h_end = end_f.dimensions(3,2);
            end_ref = [end_f.dimensions(2,1) + (h_end * normal_vec(1)), end_f.dimensions(2,2) + (h_end * normal_vec(2))];

            h_nose = nose_f.dimensions(3,2);
            nose_ref = [nose_f.dimensions(2,1) + (h_nose * normal_vec(1)), nose_f.dimensions(2,2) + (h_nose * normal_vec(2))];

            xz_ref_vec = end_ref - nose_ref;
        end

        function xy_ref_vec = compute_xy_ref_vec_f(app)
            nose_f = get_element_f(app, 'Nose Point');
            end_f = get_element_f(app, 'End Point');
            xy_ref_vec = [end_f.dimensions(1,1) - nose_f.dimensions(1,1), end_f.dimensions(1,2) - nose_f.dimensions(1,2)];             
        end
        
        function refresh_checkboxes(app, element)
            app.wing_box_checkbox_f.Value = element.is_wing_box;
            app.if_checkbox_f.Value = element.has_positive_incidence;
        end
        
        function [alpha_cl0, cl_alpha, cm_ac, did_error] = compute_naca_4(~, naca_str)

            alpha_cl0 = 0;
            cl_alpha = 0;
            cm_ac = 0;
            did_error = false;

            if ~ischar(naca_str) || ~all(ismember(naca_str, '0123456789'))
                did_error = true;
                return; 
            end
            
            naca_num = str2double(naca_str);
                   
            if (isnan(naca_num) || length(naca_str) ~= 4) 
                did_error = true;
                return; 
            end
            
            m = str2double(naca_str(1)) / 100;  % Maximum camber
            p = str2double(naca_str(2)) / 10;   % Position of maximum camber
           
            if m == 0 || p == 0 % Avoid the divide by zero case
                m = 0.0000001;
                p = 0.000001;
            end

            f = @(x) ((2 .* m) ./ (p.^2)) .* (p - x) .* (-2 .* x) .* (1 ./ sqrt(-(x-1) .* x));
            g = @(x) ((2 .* m) ./ ((1 - p).^2)) .* (p - x) .* (-2 .* x) .* (1 ./ sqrt(-(x-1) .* x));
            
            alpha_cl0 = (-1 ./ pi) * (integral(f, 0, p) + integral(g, p, 1));

            cl_alpha = 2 .* pi;  
           
            f = @(x) ((2 .* m) ./ (p.^2)) .* (p - x) .* (1 - (2 .* x)) .* (1 ./ sqrt(-(x-1) .* x));
            g = @(x) ((2 .* m) ./ ((1 - p).^2)) .* (p - x) .* (1 - (2 .* x)) .* (1 ./ sqrt(-(x-1) .* x));
              
            A1 = (2 ./ pi) .* (integral(f, 0, p) + integral(g, p, 1));
        
            f = @(x) ((2 .* m) ./ (p.^2)) .* (p - x) .* (cos(2 .* acos(1 - (2 .* x)))) .* (1 ./ sqrt(-(x-1) .* x));
            g = @(x) ((2 .* m) ./ ((1 - p).^2)) .* (p - x) .* (cos(2 .* acos(1 - (2 .* x)))) .* (1 ./ sqrt(-(x-1) .* x));
            
            A2 = (2 ./ pi) .* (integral(f, 0, p) + integral(g, p, 1));
        
            cm_ac = (pi ./ 4) .* (A2 - A1);

            % Correction from the "Avoid the divide by zero case"
            if abs(alpha_cl0) < 1e-4
                alpha_cl0 = 0;
            end

            if  abs(cm_ac) < 1e-4
                cm_ac = 0;
            end
        end
        
        function compute_from_volume_ratio_t(app)
            l_t = app.l_t_field_t.Value;
            S_t = app.tail_area_field_t.Value;
            S = app.wing_area_field_t.Value;
            c = app.wing_mean_cord_field_t.Value;

            if S == 0 || c == 0
                return;
            end

            vh = (l_t * S_t) / (S * c);

            app.v_h_field_t.Value = vh;

            compute_from_tail_static_stability(app);
        end
        
        function compute_from_tail_static_stability(app)
            
            cL_alpha_w = app.cL_alpha_w_field_w.Value;
            alpha_cl0 = app.alpha_cl0_foil_field_w.Value;

            cl_w_0 = cL_alpha_w * -(alpha_cl0);


            AR_w = app.aspect_ratio_field_w.Value;
            if AR_w == 0
                return
            end

            e0 = (2 * cl_w_0) / (pi * AR_w);
            deda = (2 * cL_alpha_w) / (pi * AR_w);
        
            n = app.tail_efficiency_field_t.Value;
            vh = app.v_h_field_t.Value;
            cL_alpha_t = app.cL_alpha_t_field_t.Value;
            iw = app.incident_wing_angle_field_t.Value;
            it = app.incident_tail_field_t.Value;

            cm_0_t = n * vh * cL_alpha_t * (e0 + iw - it);
            cm_alpha_t = -n * vh * cL_alpha_t * (1-deda);
            
            app.cm_0_t_field_t.Value = cm_0_t;
            app.cm_0_t_edit_field_r.Value = cm_0_t;
            app.cm_alpha_t_field_t.Value = cm_alpha_t;
            app.cm_alpha_t_edit_field_r.Value = cm_alpha_t;

            compute_longitudinal_static_stability_f(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            compute_from_naca_4_w(app);      
            compute_from_naca_4_t(app);
            load_three_view_image_f(app, 'nelson.jpg');
        end

        % Value changed function: aspect_ratio_field_w
        function aspect_ratio_field_w_value_changed(app, event)
            compute_from_finite_wing_correction_w(app);           
        end

        % Value changed function: span_efficiency_field_w
        function span_efficiency_field_w_value_changed(app, event)
            compute_from_finite_wing_correction_w(app);            
        end

        % Value changed function: cl_alpha_foil_field_w
        function cl_alpha_foil_field_w_value_changed(app, event)
            compute_from_finite_wing_correction_w(app);
        end

        % Value changed function: naca_4_field_w
        function naca_4_field_w_value_changed(app, event)
            compute_from_naca_4_w(app);
        end

        % Value changed function: alpha_cl0_foil_field_w
        function alpha_cl0_foil_field_w_value_changed(app, event)
            compute_from_wing_static_stability(app);
        end

        % Value changed function: cm_ac_w_field_w
        function cm_ac_w_field_w_value_changed(app, event)
            compute_from_wing_static_stability(app);   
        end

        % Value changed function: cL_alpha_w_field_w
        function cL_alpha_w_field_w_value_changed(app, event)
            compute_from_wing_static_stability(app);
        end

        % Value changed function: x_cg_c_field_w
        function x_cg_c_field_w_value_changed(app, event)
            compute_from_wing_static_stability(app);
        end

        % Value changed function: x_ac_c_field_w
        function x_ac_c_field_w_value_changed(app, event)
            compute_from_wing_static_stability(app);          
        end

        % Value changed function: filepath_field_f
        function filepath_field_f_value_changed(app, event)
            load_three_view_image_f(app, app.filepath_field_f.Value);
        end

        % Value changing function: x_xy_slider_f
        function x_xy_slider_f_value_changing(app, event)
            update_element_dimensions_f(app,1,1,event.Value,1);
        end

        % Value changing function: y_xy_slider_f
        function y_xy_slider_f_value_changing(app, event)
            update_element_dimensions_f(app,1,2,event.Value,2);
        end

        % Value changing function: x_xz_slider_f
        function x_xz_slider_f_value_changing(app, event)
            update_element_dimensions_f(app,2,1,event.Value,1);
        end

        % Value changing function: y_xz_slider_f
        function y_xz_slider_f_value_changing(app, event)
            update_element_dimensions_f(app,2,2,event.Value,2);
        end

        % Value changing function: width_slider_f
        function width_slider_f_value_changing(app, event)
            update_element_dimensions_f(app,3,1,event.Value,3); 
        end

        % Value changing function: height_slider_f
        function height_slider_f_value_changing(app, event)
            update_element_dimensions_f(app,3,2,event.Value,3);     
        end

        % Value changed function: element_drop_down_f
        function element_drop_down_f_value_changed(app, event)
            for i = 1:length(app.elements_f)               
                if strcmp(app.elements_f(i).name, app.element_drop_down_f.Value)
                    app.elements_f(i).is_selected = true;

                    if strcmp('Nose Point', app.element_drop_down_f.Value) || strcmp('End Point', app.element_drop_down_f.Value)
                        app.width_slider_f.Enable = false;
                        app.height_slider_f.Enable = true;
                    else
                        app.width_slider_f.Enable = true;
                        app.height_slider_f.Enable = false;
                    end

                    refresh_sliders_f(app, app.elements_f(i));
                    refresh_checkboxes(app, app.elements_f(i));
                else
                    app.elements_f(i).is_selected = false;
                end
            end
            refresh_three_view_plot_f(app);
        end

        % Value changed function: wing_box_checkbox_f
        function wing_box_checkbox_f_value_changed(app, event)
            for i = 1:length(app.elements_f)
                if app.elements_f(i).is_selected                   
                    app.elements_f(i).is_wing_box = app.wing_box_checkbox_f.Value;
                    compute_longitudinal_static_stability_f(app);
                    return
                end
            end
        end

        % Value changed function: if_checkbox_f
        function if_checkbox_f_value_changed(app, event)
            for i = 1:length(app.elements_f)
                if app.elements_f(i).is_selected                   
                    app.elements_f(i).has_positive_incidence = app.if_checkbox_f.Value;
                    compute_longitudinal_static_stability_f(app);
                    return
                end
            end       
        end

        % Value changed function: fuselage_length_edit_field_f
        function fuselage_length_edit_field_f_value_changed(app, event)
            compute_longitudinal_static_stability_f(app);
        end

        % Value changed function: mean_cord_edit_field_f
        function mean_cord_edit_field_f_value_changed(app, event)
            compute_longitudinal_static_stability_f(app);
        end

        % Value changed function: a0w_edit_field_f
        function a0w_edit_field_f_value_changed(app, event)
           compute_longitudinal_static_stability_f(app);
        end

        % Value changed function: wing_area_edit_field_f
        function wing_area_edit_field_f_value_changed(app, event)
            compute_longitudinal_static_stability_f(app);
        end

        % Value changed function: naca4_edit_field_t
        function naca4_edit_field_t_value_changed(app, event)
            compute_from_naca_4_t(app);          
        end

        % Value changed function: aspect_ratio_field_t
        function aspect_ratio_field_t_value_changed(app, event)
            compute_from_finite_wing_correction_t(app);
        end

        % Value changed function: span_efficiency_field_t
        function span_efficiency_field_t_value_changed(app, event)
            compute_from_finite_wing_correction_t(app);
        end

        % Value changed function: cl_alpha_foil_field_t
        function cl_alpha_foil_field_t_value_changed(app, event)
            compute_from_finite_wing_correction_t(app);
        end

        % Value changed function: l_t_field_t
        function l_t_field_t_value_changed(app, event)
            compute_from_volume_ratio_t(app);
        end

        % Value changed function: tail_area_field_t
        function tail_area_field_t_value_changed(app, event)
            compute_from_volume_ratio_t(app);
        end

        % Value changed function: wing_mean_cord_field_t
        function wing_mean_cord_field_t_value_changed(app, event)
            compute_from_volume_ratio_t(app);   
        end

        % Value changed function: wing_area_field_t
        function wing_area_field_t_value_changed(app, event)
            compute_from_volume_ratio_t(app);
        end

        % Value changed function: v_h_field_t
        function v_h_field_t_value_changed(app, event)
            compute_from_tail_static_stability(app);            
        end

        % Value changed function: tail_efficiency_field_t
        function tail_efficiency_field_t_value_changed(app, event)
            compute_from_tail_static_stability(app);
        end

        % Value changed function: incident_wing_angle_field_t
        function incident_wing_angle_field_t_value_changed(app, event)
            compute_from_tail_static_stability(app);
        end

        % Value changed function: incident_tail_field_t
        function incident_tail_field_t_value_changed(app, event)
            compute_from_tail_static_stability(app);
        end

        % Value changed function: cL_alpha_t_field_t
        function cL_alpha_t_field_t_value_changed(app, event)
            compute_from_tail_static_stability(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.wing = uitab(app.TabGroup);
            app.wing.Title = 'Wing';
            app.stability_plot_w = uiaxes(app.wing);
            title(app.stability_plot_w, 'Wing Longitudinal Static Stability')
            xlabel(app.stability_plot_w, 'alpha')
            ylabel(app.stability_plot_w, 'cm')
            zlabel(app.stability_plot_w, 'Z')
            app.Alphacl0Label = uilabel(app.wing);
            app.Alphacl0Label.Text = 'alpha_cl0 [rads]';
            app.Alphacl0Label_3 = uilabel(app.wing);
            app.Alphacl0Label_3.Text = 'cm_ac_w';
            app.cm_ac_w_field_w = uieditfield(app.wing, 'numeric');
            app.cm_ac_w_field_w.ValueChangedFcn = createCallbackFcn(app, @cm_ac_w_field_w_value_changed, true);
            app.Alphacl0Label_2 = uilabel(app.wing);
            app.Alphacl0Label_2.Text = 'cl_alpha [1/rads]';
            app.cl_alpha_foil_field_w = uieditfield(app.wing, 'numeric');
            app.cl_alpha_foil_field_w.ValueChangedFcn = createCallbackFcn(app, @cl_alpha_foil_field_w_value_changed, true);
            app.alpha_cl0_foil_field_w = uieditfield(app.wing, 'numeric');
            app.alpha_cl0_foil_field_w.ValueChangedFcn = createCallbackFcn(app, @alpha_cl0_foil_field_w_value_changed, true);
            app.Alphacl0Label_4 = uilabel(app.wing);
            app.Alphacl0Label_4.Text = 'Aspect Ratio';
            app.aspect_ratio_field_w = uieditfield(app.wing, 'numeric');
            app.aspect_ratio_field_w.ValueChangedFcn = createCallbackFcn(app, @aspect_ratio_field_w_value_changed, true);
            app.aspect_ratio_field_w.Value = 7;
            app.Alphacl0Label_5 = uilabel(app.wing);
            app.Alphacl0Label_5.Text = 'Span Efficiency';
            app.span_efficiency_field_w = uieditfield(app.wing, 'numeric');
            app.span_efficiency_field_w.ValueChangedFcn = createCallbackFcn(app, @span_efficiency_field_w_value_changed, true);
            app.span_efficiency_field_w.Value = 0.9;
            app.Alphacl0Label_6 = uilabel(app.wing);
            app.Alphacl0Label_6.Text = 'cL_alpha_w [1/rads]';
            app.cL_alpha_w_field_w = uieditfield(app.wing, 'numeric');
            app.cL_alpha_w_field_w.ValueChangedFcn = createCallbackFcn(app, @cL_alpha_w_field_w_value_changed, true);
            app.naca_4_field_w = uieditfield(app.wing, 'text');
            app.naca_4_field_w.ValueChangedFcn = createCallbackFcn(app, @naca_4_field_w_value_changed, true);
            app.naca_4_field_w.Value = '2412';
            app.wing_airfoil_data_label_w = uilabel(app.wing);
            app.wing_airfoil_data_label_w.Text = 'WING AIRFOIL DATA';
            app.finite_wing_correction_label_w = uilabel(app.wing);
            app.finite_wing_correction_label_w.Text = 'FINITE WING CORRECTION';
            app.NACA4SeriesAirfoilEditFieldLabel = uilabel(app.wing);
            app.NACA4SeriesAirfoilEditFieldLabel.Text = 'NACA 4 Series Airfoil';
            app.cg_and_ac_locations_label_w = uilabel(app.wing);
            app.cg_and_ac_locations_label_w.Text = 'CG AND AC LOCATIONS';
            app.Alphacl0Label_7 = uilabel(app.wing);
            app.Alphacl0Label_7.Text = 'x_cg/c';
            app.x_cg_c_field_w = uieditfield(app.wing, 'numeric');
            app.x_cg_c_field_w.ValueChangedFcn = createCallbackFcn(app, @x_cg_c_field_w_value_changed, true);
            app.x_cg_c_field_w.Value = 0.3;
            app.Alphacl0Label_8 = uilabel(app.wing);
            app.Alphacl0Label_8.Text = 'x_ac/c';
            app.x_ac_c_field_w = uieditfield(app.wing, 'numeric');
            app.x_ac_c_field_w.ValueChangedFcn = createCallbackFcn(app, @x_ac_c_field_w_value_changed, true);
            app.x_ac_c_field_w.Value = 0.25;
            app.static_stability_label_w = uilabel(app.wing);
            app.static_stability_label_w.Text = 'STATIC STABILITY';
            app.Alphacl0Label_9 = uilabel(app.wing);
            app.Alphacl0Label_9.Text = 'cm_0_w';
            app.cm_0_w_field_w = uieditfield(app.wing, 'numeric');
            app.Alphacl0Label_10 = uilabel(app.wing);
            app.Alphacl0Label_10.Text = 'cm_alpha_w [1/rads]';
            app.cm_alpha_w_field_w = uieditfield(app.wing, 'numeric');
            app.tail = uitab(app.TabGroup);
            app.tail.Title = 'Tail';
            app.tail_airfoil_data_label_t = uilabel(app.tail);
            app.tail_airfoil_data_label_t.Text = 'TAIL AIRFOIL DATA';
            app.Alphacl0Label_11 = uilabel(app.tail);
            app.Alphacl0Label_11.Text = 'alpha_cl0 [rads]';
            app.alpha_cl0_foil_field_t = uieditfield(app.tail, 'numeric');
            app.Alphacl0Label_12 = uilabel(app.tail);
            app.Alphacl0Label_12.Text = 'cl_alpha [1/rads]';
            app.cl_alpha_foil_field_t = uieditfield(app.tail, 'numeric');
            app.cl_alpha_foil_field_t.ValueChangedFcn = createCallbackFcn(app, @cl_alpha_foil_field_t_value_changed, true);
            app.Alphacl0Label_13 = uilabel(app.tail);
            app.Alphacl0Label_13.Text = 'cm_ac_t';
            app.cm_ac_t_field_t = uieditfield(app.tail, 'numeric');
            app.finite_wing_correction_label_t = uilabel(app.tail);
            app.finite_wing_correction_label_t.Text = 'FINITE WING CORRECTION';
            app.AspectRatiotailEditFieldLabel = uilabel(app.tail);
            app.AspectRatiotailEditFieldLabel.Text = 'Aspect Ratio (tail)';
            app.aspect_ratio_field_t = uieditfield(app.tail, 'numeric');
            app.aspect_ratio_field_t.ValueChangedFcn = createCallbackFcn(app, @aspect_ratio_field_t_value_changed, true);
            app.aspect_ratio_field_t.Value = 7;
            app.SpanEfficiencyEditFieldLabel = uilabel(app.tail);
            app.SpanEfficiencyEditFieldLabel.Text = 'Span Efficiency';
            app.span_efficiency_field_t = uieditfield(app.tail, 'numeric');
            app.span_efficiency_field_t.ValueChangedFcn = createCallbackFcn(app, @span_efficiency_field_t_value_changed, true);
            app.span_efficiency_field_t.Value = 0.9;
            app.cL_alpha_tEditFieldLabel = uilabel(app.tail);
            app.cL_alpha_tEditFieldLabel.Text = 'cL_alpha_t';
            app.cL_alpha_t_field_t = uieditfield(app.tail, 'numeric');
            app.cL_alpha_t_field_t.ValueChangedFcn = createCallbackFcn(app, @cL_alpha_t_field_t_value_changed, true);
            app.finite_wing_correction_label_t_2 = uilabel(app.tail);
            app.finite_wing_correction_label_t_2.Text = 'VOLUME RATIO';
            app.ltLabel = uilabel(app.tail);
            app.ltLabel.Text = 'Length to Tail';
            app.l_t_field_t = uieditfield(app.tail, 'numeric');
            app.l_t_field_t.ValueChangedFcn = createCallbackFcn(app, @l_t_field_t_value_changed, true);
            app.l_t_field_t.Value = 16;
            app.ltLabel_2 = uilabel(app.tail);
            app.ltLabel_2.Text = 'Tail Area';
            app.tail_area_field_t = uieditfield(app.tail, 'numeric');
            app.tail_area_field_t.ValueChangedFcn = createCallbackFcn(app, @tail_area_field_t_value_changed, true);
            app.tail_area_field_t.Value = 43;
            app.ltLabel_3 = uilabel(app.tail);
            app.ltLabel_3.Text = 'WING Mean Cord';
            app.wing_mean_cord_field_t = uieditfield(app.tail, 'numeric');
            app.wing_mean_cord_field_t.ValueChangedFcn = createCallbackFcn(app, @wing_mean_cord_field_t_value_changed, true);
            app.wing_mean_cord_field_t.Value = 5.7;
            app.ltLabel_4 = uilabel(app.tail);
            app.ltLabel_4.Text = 'Volume Ratio';
            app.v_h_field_t = uieditfield(app.tail, 'numeric');
            app.v_h_field_t.ValueChangedFcn = createCallbackFcn(app, @v_h_field_t_value_changed, true);
            app.other_inputs_label_t = uilabel(app.tail);
            app.other_inputs_label_t.Text = 'OTHER INPUTS';
            app.ltLabel_5 = uilabel(app.tail);
            app.ltLabel_5.Text = 'Tail Efficiency';
            app.tail_efficiency_field_t = uieditfield(app.tail, 'numeric');
            app.tail_efficiency_field_t.ValueChangedFcn = createCallbackFcn(app, @tail_efficiency_field_t_value_changed, true);
            app.tail_efficiency_field_t.Value = 1;
            app.ltLabel_6 = uilabel(app.tail);
            app.ltLabel_6.Text = 'Incident Wing [rad]';
            app.incident_wing_angle_field_t = uieditfield(app.tail, 'numeric');
            app.incident_wing_angle_field_t.ValueChangedFcn = createCallbackFcn(app, @incident_wing_angle_field_t_value_changed, true);
            app.incident_wing_angle_field_t.Value = 0.0175;
            app.ltLabel_7 = uilabel(app.tail);
            app.ltLabel_7.Text = 'Incident Tail [rad]';
            app.incident_tail_field_t = uieditfield(app.tail, 'numeric');
            app.incident_tail_field_t.ValueChangedFcn = createCallbackFcn(app, @incident_tail_field_t_value_changed, true);
            app.incident_tail_field_t.Value = -0.0175;
            app.static_stability_filed_t = uilabel(app.tail);
            app.static_stability_filed_t.Text = 'STATIC STABILITY';
            app.ltLabel_8 = uilabel(app.tail);
            app.ltLabel_8.Text = 'cm_0_t';
            app.cm_0_t_field_t = uieditfield(app.tail, 'numeric');
            app.ltLabel_9 = uilabel(app.tail);
            app.ltLabel_9.Text = 'cm_alpha_t [1/rads]';
            app.cm_alpha_t_field_t = uieditfield(app.tail, 'numeric');
            app.NACA4SeriesAirfoilEditFieldLabel_2 = uilabel(app.tail);
            app.NACA4SeriesAirfoilEditFieldLabel_2.Text = 'NACA 4 Series Airfoil';
            app.naca4_edit_field_t = uieditfield(app.tail, 'text');
            app.naca4_edit_field_t.ValueChangedFcn = createCallbackFcn(app, @naca4_edit_field_t_value_changed, true);
            app.naca4_edit_field_t.Value = '0012';
            app.ltLabel_10 = uilabel(app.tail);
            app.ltLabel_10.Text = 'WING Area';
            app.wing_area_field_t = uieditfield(app.tail, 'numeric');
            app.wing_area_field_t.ValueChangedFcn = createCallbackFcn(app, @wing_area_field_t_value_changed, true);
            app.wing_area_field_t.Value = 184;
            app.fuselage = uitab(app.TabGroup);
            app.fuselage.Title = 'Fuselage';
            app.StepOneSpecifya3ViewImageFilepathjpgrecommendedLabel = uilabel(app.fuselage);
            app.StepOneSpecifya3ViewImageFilepathjpgrecommendedLabel.Text = 'Step One: Specify a 3-View Image Filepath (.jpg recommended)';
            app.filepath_field_f = uieditfield(app.fuselage, 'text');
            app.filepath_field_f.ValueChangedFcn = createCallbackFcn(app, @filepath_field_f_value_changed, true);
            app.filepath_field_f.Value = 'nelson.jpg';
            app.three_view_container = uipanel(app.fuselage);
            app.three_view_container.Title = '3-View';
            app.three_view_plot = uiaxes(app.three_view_container);
            title(app.three_view_plot, 'Title')
            zlabel(app.three_view_plot, 'Z')
            app.three_view_plot.XTick = [];
            app.three_view_plot.YTick = [];
            app.fuselage_geometry_editor_f = uipanel(app.fuselage);
            app.fuselage_geometry_editor_f.Title = 'Step Two: Define Fuselage Geometry';
            app.element_drop_down_f = uidropdown(app.fuselage_geometry_editor_f);
            app.element_drop_down_f.Items = {'Nose Point', 'End Point'};
            app.element_drop_down_f.ValueChangedFcn = createCallbackFcn(app, @element_drop_down_f_value_changed, true);
            app.element_drop_down_f.Value = 'Nose Point';
            app.XLabel = uilabel(app.fuselage_geometry_editor_f);
            app.XLabel.Text = 'X (xy)';
            app.x_xy_slider_f = uislider(app.fuselage_geometry_editor_f);
            app.x_xy_slider_f.Limits = [0 1];
            app.x_xy_slider_f.ValueChangingFcn = createCallbackFcn(app, @x_xy_slider_f_value_changing, true);
            app.x_xy_slider_f.MinorTicks = [0 0.015 0.03 0.045 0.06 0.075 0.09 0.105 0.12 0.135 0.15 0.165 0.18 0.195 0.21 0.225 0.24 0.255 0.27 0.285 0.3 0.315 0.33 0.345 0.36 0.375 0.39 0.405 0.42 0.435 0.45 0.465 0.48 0.495 0.51 0.525 0.54 0.555 0.57 0.585 0.6 0.615 0.63 0.645 0.66 0.675 0.69 0.705 0.72 0.735 0.75 0.765 0.78 0.795 0.81 0.825 0.84 0.855 0.87 0.885 0.9 0.915 0.93 0.945 0.96 0.975 0.99];
            app.NoseYLabel = uilabel(app.fuselage_geometry_editor_f);
            app.NoseYLabel.Text = 'Y (xy)';
            app.y_xy_slider_f = uislider(app.fuselage_geometry_editor_f);
            app.y_xy_slider_f.Limits = [0 1];
            app.y_xy_slider_f.ValueChangingFcn = createCallbackFcn(app, @y_xy_slider_f_value_changing, true);
            app.y_xy_slider_f.MinorTicks = [0 0.015 0.03 0.045 0.06 0.075 0.09 0.105 0.12 0.135 0.15 0.165 0.18 0.195 0.21 0.225 0.24 0.255 0.27 0.285 0.3 0.315 0.33 0.345 0.36 0.375 0.39 0.405 0.42 0.435 0.45 0.465 0.48 0.495 0.51 0.525 0.54 0.555 0.57 0.585 0.6 0.615 0.63 0.645 0.66 0.675 0.69 0.705 0.72 0.735 0.75 0.765 0.78 0.795 0.81 0.825 0.84 0.855 0.87 0.885 0.9 0.915 0.93 0.945 0.96 0.975 0.99];
            app.directions_f = uitextarea(app.fuselage_geometry_editor_f);
            app.directions_f.Editable = 'off';
            app.directions_f.Value = {'To determine fuselage stability characteristics, we must discretize the geometry. Please upload a 3-view drawing and define the nose, endpoint, and subdivisions in the XY and XZ planes. The static stability fields and plots will be updated automatically. Points in the XY plane should be placed along the longitudinal axis, while points in the XZ plane should be positioned on the fuselage camber line. Additionally, define a reference vector for both the nose and the endpoint such that they intersect the fuselage reference line in the XZ plane. Use the width slider to match the fuselage geometry in the XY plane. Make sure to define which subdivisions are part of the wing box (includes leading and trailing edges).'};
            app.XLabel_2 = uilabel(app.fuselage_geometry_editor_f);
            app.XLabel_2.Text = 'X (xz)';
            app.x_xz_slider_f = uislider(app.fuselage_geometry_editor_f);
            app.x_xz_slider_f.Limits = [0 1];
            app.x_xz_slider_f.ValueChangingFcn = createCallbackFcn(app, @x_xz_slider_f_value_changing, true);
            app.x_xz_slider_f.MinorTicks = [0 0.015 0.03 0.045 0.06 0.075 0.09 0.105 0.12 0.135 0.15 0.165 0.18 0.195 0.21 0.225 0.24 0.255 0.27 0.285 0.3 0.315 0.33 0.345 0.36 0.375 0.39 0.405 0.42 0.435 0.45 0.465 0.48 0.495 0.51 0.525 0.54 0.555 0.57 0.585 0.6 0.615 0.63 0.645 0.66 0.675 0.69 0.705 0.72 0.735 0.75 0.765 0.78 0.795 0.81 0.825 0.84 0.855 0.87 0.885 0.9 0.915 0.93 0.945 0.96 0.975 0.99];
            app.NoseYLabel_2 = uilabel(app.fuselage_geometry_editor_f);
            app.NoseYLabel_2.Text = 'Z (xz)';
            app.y_xz_slider_f = uislider(app.fuselage_geometry_editor_f);
            app.y_xz_slider_f.Limits = [0 1];
            app.y_xz_slider_f.ValueChangingFcn = createCallbackFcn(app, @y_xz_slider_f_value_changing, true);
            app.y_xz_slider_f.MinorTicks = [0 0.015 0.03 0.045 0.06 0.075 0.09 0.105 0.12 0.135 0.15 0.165 0.18 0.195 0.21 0.225 0.24 0.255 0.27 0.285 0.3 0.315 0.33 0.345 0.36 0.375 0.39 0.405 0.42 0.435 0.45 0.465 0.48 0.495 0.51 0.525 0.54 0.555 0.57 0.585 0.6 0.615 0.63 0.645 0.66 0.675 0.69 0.705 0.72 0.735 0.75 0.765 0.78 0.795 0.81 0.825 0.84 0.855 0.87 0.885 0.9 0.915 0.93 0.945 0.96 0.975 0.99];
            app.XLabel_3 = uilabel(app.fuselage_geometry_editor_f);
            app.XLabel_3.Text = 'Width';
            app.width_slider_f = uislider(app.fuselage_geometry_editor_f);
            app.width_slider_f.Limits = [0 1];
            app.width_slider_f.ValueChangingFcn = createCallbackFcn(app, @width_slider_f_value_changing, true);
            app.width_slider_f.MinorTicks = [0 0.015 0.03 0.045 0.06 0.075 0.09 0.105 0.12 0.135 0.15 0.165 0.18 0.195 0.21 0.225 0.24 0.255 0.27 0.285 0.3 0.315 0.33 0.345 0.36 0.375 0.39 0.405 0.42 0.435 0.45 0.465 0.48 0.495 0.51 0.525 0.54 0.555 0.57 0.585 0.6 0.615 0.63 0.645 0.66 0.675 0.69 0.705 0.72 0.735 0.75 0.765 0.78 0.795 0.81 0.825 0.84 0.855 0.87 0.885 0.9 0.915 0.93 0.945 0.96 0.975 0.99];
            app.XLabel_4 = uilabel(app.fuselage_geometry_editor_f);
            app.XLabel_4.Text = 'Ref';
            app.height_slider_f = uislider(app.fuselage_geometry_editor_f);
            app.height_slider_f.Limits = [-1 1];
            app.height_slider_f.ValueChangingFcn = createCallbackFcn(app, @height_slider_f_value_changing, true);
            app.height_slider_f.MinorTicks = [-1 -0.96 -0.92 -0.88 -0.84 -0.8 -0.76 -0.72 -0.68 -0.64 -0.6 -0.56 -0.52 -0.48 -0.44 -0.4 -0.36 -0.32 -0.28 -0.24 -0.2 -0.16 -0.12 -0.08 -0.04 0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.if_checkbox_f = uicheckbox(app.fuselage_geometry_editor_f);
            app.if_checkbox_f.ValueChangedFcn = createCallbackFcn(app, @if_checkbox_f_value_changed, true);
            app.if_checkbox_f.Text = 'Positive Incidence';
            app.wing_box_checkbox_f = uicheckbox(app.fuselage_geometry_editor_f);
            app.wing_box_checkbox_f.ValueChangedFcn = createCallbackFcn(app, @wing_box_checkbox_f_value_changed, true);
            app.wing_box_checkbox_f.Text = 'Wing Box';
            app.fuselage_geometry_definition_f = uipanel(app.fuselage);
            app.fuselage_geometry_definition_f.Title = 'Step Three: Define Aircraft Geometry';
            app.FuselageLengthEditFieldLabel = uilabel(app.fuselage_geometry_definition_f);
            app.FuselageLengthEditFieldLabel.Text = 'Fuselage Length';
            app.fuselage_length_edit_field_f = uieditfield(app.fuselage_geometry_definition_f, 'numeric');
            app.fuselage_length_edit_field_f.ValueChangedFcn = createCallbackFcn(app, @fuselage_length_edit_field_f_value_changed, true);
            app.MeanCordEditFieldLabel = uilabel(app.fuselage_geometry_definition_f);
            app.MeanCordEditFieldLabel.Text = 'Mean Cord';
            app.mean_cord_edit_field_f = uieditfield(app.fuselage_geometry_definition_f, 'numeric');
            app.mean_cord_edit_field_f.ValueChangedFcn = createCallbackFcn(app, @mean_cord_edit_field_f_value_changed, true);
            app.a0wradLabel = uilabel(app.fuselage_geometry_definition_f);
            app.a0wradLabel.Text = 'a0w [rad]';
            app.a0w_edit_field_f = uieditfield(app.fuselage_geometry_definition_f, 'numeric');
            app.a0w_edit_field_f.ValueChangedFcn = createCallbackFcn(app, @a0w_edit_field_f_value_changed, true);
            app.WingAreaSLabel = uilabel(app.fuselage_geometry_definition_f);
            app.WingAreaSLabel.Text = 'Wing Area (S)';
            app.wing_area_edit_field_f = uieditfield(app.fuselage_geometry_definition_f, 'numeric');
            app.wing_area_edit_field_f.ValueChangedFcn = createCallbackFcn(app, @wing_area_edit_field_f_value_changed, true);
            app.results_panel = uipanel(app.fuselage);
            app.results_panel.Title = 'Step Four: Results';
            app.LongitudinalStaticStabilityLampLabel = uilabel(app.results_panel);
            app.LongitudinalStaticStabilityLampLabel.Text = 'Longitudinal Static Stability';
            app.stability_lamp_r = uilamp(app.results_panel);
            app.cm_0_fEditFieldLabel = uilabel(app.results_panel);
            app.cm_0_fEditFieldLabel.Text = 'cm_0_f';
            app.cm_0_f_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_0_f_edit_field_r.Editable = 'off';
            app.cm_alpha_f1radsEditFieldLabel = uilabel(app.results_panel);
            app.cm_alpha_f1radsEditFieldLabel.Text = 'cm_alpha_f [1/rads]';
            app.cm_alpha_f_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_alpha_f_edit_field_r.Editable = 'off';
            app.cm_0_wEditFieldLabel = uilabel(app.results_panel);
            app.cm_0_wEditFieldLabel.Text = 'cm_0_w';
            app.cm_0_w_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_0_w_edit_field_r.Editable = 'off';
            app.cm_alpha_w1radsEditFieldLabel = uilabel(app.results_panel);
            app.cm_alpha_w1radsEditFieldLabel.Text = 'cm_alpha_w [1/rads]';
            app.cm_alpha_w_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_alpha_w_edit_field_r.Editable = 'off';
            app.cm_0_tEditFieldLabel = uilabel(app.results_panel);
            app.cm_0_tEditFieldLabel.Text = 'cm_0_t';
            app.cm_0_t_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_0_t_edit_field_r.Editable = 'off';
            app.cm_alpha_t1radsEditFieldLabel = uilabel(app.results_panel);
            app.cm_alpha_t1radsEditFieldLabel.Text = 'cm_alpha_t [1/rads]';
            app.cm_alpha_t_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_alpha_t_edit_field_r.Editable = 'off';
            app.cM_alpha1radsLabel = uilabel(app.results_panel);
            app.cM_alpha1radsLabel.Text = {'cm_0'; ''};
            app.cm_0_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_0_edit_field_r.Editable = 'off';
            app.cm_alpha1radsLabel = uilabel(app.results_panel);
            app.cm_alpha1radsLabel.Text = 'cm_alpha [1/rads]';
            app.cm_alpha_edit_field_r = uieditfield(app.results_panel, 'numeric');
            app.cm_alpha_edit_field_r.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = longitudinal_stability_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end