classdef performance_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ISATab                          matlab.ui.container.Tab
        DesignedbyRyanKing0959315Label_2  matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        AtmosphereLayersTab             matlab.ui.container.Tab
        AtmLayers                       matlab.ui.control.Table
        AdditionalInformationTab        matlab.ui.container.Tab
        TheEarthsatmosphereiscomprisedofLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GeopotentialAltitudemEditField  matlab.ui.control.NumericEditField
        TemperatureKEditField           matlab.ui.control.NumericEditField
        SpeedofSoundmsEditField         matlab.ui.control.NumericEditField
        PressurePaEditField             matlab.ui.control.NumericEditField
        Densitykgm3EditField            matlab.ui.control.NumericEditField
        Viscositym2sEditField           matlab.ui.control.NumericEditField
        Viscositym2sEditFieldLabel      matlab.ui.control.Label
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        PressurePaEditFieldLabel        matlab.ui.control.Label
        SpeedofSoundmsEditFieldLabel    matlab.ui.control.Label
        TemperatureKEditFieldLabel      matlab.ui.control.Label
        GeopotentialAltitudemEditFieldLabel  matlab.ui.control.Label
        ViscosityAltitudeAxis           matlab.ui.control.UIAxes
        SpeedAltitudeAxis               matlab.ui.control.UIAxes
        DensityAltitudeAxis             matlab.ui.control.UIAxes
        PressureAltitudeAxis            matlab.ui.control.UIAxes
        TemperatureAltitudeAxis         matlab.ui.control.UIAxes
        CSATTab                         matlab.ui.container.Tab
        DesignedbyRyanKing0959315Label_3  matlab.ui.control.Label
        VisualGuidetoCoordinateSystemTransformationsPanel  matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        EarthtoBodyTab                  matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        BodyCoordinateSystemLabel       matlab.ui.control.Label
        TransformedUsingRollPitchandYawLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        EarthCoordinateSystemLabel      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        BodytoStabilitytoWindTab        matlab.ui.container.Tab
        Image4                          matlab.ui.control.Image
        BodytoStabilityRotatesbyAngleAlphaasShownLabel  matlab.ui.control.Label
        WindtoVelocityTab               matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        BodytoStabilityRotatesbyAngleAlphaasShownLabel_2  matlab.ui.control.Label
        VelocitytoEarthTab              matlab.ui.container.Tab
        Image6                          matlab.ui.control.Image
        BodytoStabilityRotatesbyAngleAlphaasShownLabel_3  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        TransformationAnglesDegreesPanel  matlab.ui.container.Panel
        Gamma3Spinner                   matlab.ui.control.Spinner
        Gamma3SpinnerLabel              matlab.ui.control.Label
        Gamma2Spinner                   matlab.ui.control.Spinner
        Gamma2SpinnerLabel              matlab.ui.control.Label
        Gamma1Spinner                   matlab.ui.control.Spinner
        Gamma1SpinnerLabel              matlab.ui.control.Label
        BetaSpinner                     matlab.ui.control.Spinner
        BetaSpinnerLabel                matlab.ui.control.Label
        AlphaSpinner                    matlab.ui.control.Spinner
        AlphaSpinnerLabel               matlab.ui.control.Label
        PanelOut                        matlab.ui.container.Panel
        outXEditField                   matlab.ui.control.NumericEditField
        outYEditField                   matlab.ui.control.NumericEditField
        outZEditField                   matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        YEditField_2Label               matlab.ui.control.Label
        XEditField_2Label               matlab.ui.control.Label
        CoordinateSystemOutDropDown     matlab.ui.control.DropDown
        OutputCoordinateSystemLabel     matlab.ui.control.Label
        PanelIn                         matlab.ui.container.Panel
        inXEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        inYEditField                    matlab.ui.control.NumericEditField
        inZEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        CoordinateSystemInDropDown      matlab.ui.control.DropDown
        InputCoordinateSystemLabel      matlab.ui.control.Label
        AircraftOrientationAnglesDegreesPanel  matlab.ui.container.Panel
        ArtificialHorizon               Aero.ui.control.ArtificialHorizon
        RollSpinner                     matlab.ui.control.Spinner
        PitchSpinner                    matlab.ui.control.Spinner
        YawSpinner                      matlab.ui.control.Spinner
        YawSpinnerLabel                 matlab.ui.control.Label
        PitchSpinnerLabel               matlab.ui.control.Label
        RollSpinnerLabel                matlab.ui.control.Label
        PerformanceTab                  matlab.ui.container.Tab
        HelpPanel                       matlab.ui.container.Panel
        helpLabel                       matlab.ui.control.Label
        GlidePerformance                matlab.ui.container.Panel
        inGlidingTime                   matlab.ui.control.NumericEditField
        WingspanLabel_31                matlab.ui.control.Label
        inDescentRate                   matlab.ui.control.NumericEditField
        WingspanLabel_30                matlab.ui.control.Label
        inInitHeight                    matlab.ui.control.NumericEditField
        WingspanLabel_29                matlab.ui.control.Label
        inMinGlideAngle                 matlab.ui.control.NumericEditField
        WingspanLabel_28                matlab.ui.control.Label
        GlidePerformanceLabel           matlab.ui.control.Label
        ClimbPerformaceInput            matlab.ui.container.Panel
        inPropPower                     matlab.ui.control.NumericEditField
        WingspanLabel_27                matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        inClimbWeight                   matlab.ui.control.NumericEditField
        WingspanLabel_24                matlab.ui.control.Label
        inClimbWingLoading              matlab.ui.control.NumericEditField
        WingspanLabel_23                matlab.ui.control.Label
        inClimbThrustToWeight           matlab.ui.control.NumericEditField
        WingspanLabel_22                matlab.ui.control.Label
        ClimbPerformanceInputsLabel     matlab.ui.control.Label
        ClimbPerformance                matlab.ui.container.Panel
        inPropVRCMax                    matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_8     matlab.ui.control.Label
        inPropThetaRCMax                matlab.ui.control.NumericEditField
        WingspanLabel_26                matlab.ui.control.Label
        inJetThetaRCMax                 matlab.ui.control.NumericEditField
        WingspanLabel_25                matlab.ui.control.Label
        inPropRCMax                     matlab.ui.control.NumericEditField
        WingspanLabel_21                matlab.ui.control.Label
        inPropThetaMax                  matlab.ui.control.NumericEditField
        AspectRatioLabel_8              matlab.ui.control.Label
        inPropVThetaMax                 matlab.ui.control.NumericEditField
        WingspanLabel_20                matlab.ui.control.Label
        ClimbPerformancePropellerLabel  matlab.ui.control.Label
        ClimbPerformanceJetLabel        matlab.ui.control.Label
        inJetVThetaMax                  matlab.ui.control.NumericEditField
        WingspanLabel_19                matlab.ui.control.Label
        inJetThetaMax                   matlab.ui.control.NumericEditField
        AspectRatioLabel_7              matlab.ui.control.Label
        inJetVRCMax                     matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_7     matlab.ui.control.Label
        inJetRCMax                      matlab.ui.control.NumericEditField
        WingspanLabel_18                matlab.ui.control.Label
        CruisePerformance               matlab.ui.container.Panel
        CruisePerformanceLabel          matlab.ui.control.Label
        inPropEndurance                 matlab.ui.control.NumericEditField
        WingspanLabel_17                matlab.ui.control.Label
        inJetEndurance                  matlab.ui.control.NumericEditField
        AspectRatioLabel_6              matlab.ui.control.Label
        inPropRange                     matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_6     matlab.ui.control.Label
        inJetRange                      matlab.ui.control.NumericEditField
        WingspanLabel_16                matlab.ui.control.Label
        CalculatedParameters            matlab.ui.container.Panel
        inPropEfficiency                matlab.ui.control.NumericEditField
        WingspanLabel_15                matlab.ui.control.Label
        OverideLabel                    matlab.ui.control.Label
        OverideParametersPanel          matlab.ui.container.Panel
        OverideParametersSwitch         matlab.ui.control.Switch
        inThrust                        matlab.ui.control.NumericEditField
        WingspanLabel_14                matlab.ui.control.Label
        inWeight                        matlab.ui.control.NumericEditField
        WingspanLabel_13                matlab.ui.control.Label
        inMaxLD                         matlab.ui.control.NumericEditField
        WingspanLabel_12                matlab.ui.control.Label
        inWingLoading                   matlab.ui.control.NumericEditField
        WingspanLabel_11                matlab.ui.control.Label
        inThrustToWeight                matlab.ui.control.NumericEditField
        WingspanLabel_10                matlab.ui.control.Label
        inLD                            matlab.ui.control.NumericEditField
        WingspanLabel_9                 matlab.ui.control.Label
        inFinalMass                     matlab.ui.control.NumericEditField
        WingspanLabel_8                 matlab.ui.control.Label
        inInitialMass                   matlab.ui.control.NumericEditField
        WingspanLabel_7                 matlab.ui.control.Label
        CalculatedParametersforCruisePerformanceLabel  matlab.ui.control.Label
        inDrag                          matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_5     matlab.ui.control.Label
        inLift                          matlab.ui.control.NumericEditField
        WingspanLabel_6                 matlab.ui.control.Label
        FuelParameters                  matlab.ui.container.Panel
        FuelParametersLabel             matlab.ui.control.Label
        inTSFC                          matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_4     matlab.ui.control.Label
        inFuelMass                      matlab.ui.control.NumericEditField
        WingspanLabel_5                 matlab.ui.control.Label
        AerodynamicCoeficients          matlab.ui.container.Panel
        inK                             matlab.ui.control.NumericEditField
        AspectRatioLabel_5              matlab.ui.control.Label
        inCD                            matlab.ui.control.NumericEditField
        AspectRatioLabel_4              matlab.ui.control.Label
        AerodynamicCoeficientsLabel     matlab.ui.control.Label
        inOswald                        matlab.ui.control.NumericEditField
        AspectRatioLabel_3              matlab.ui.control.Label
        inCD0                           matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_3     matlab.ui.control.Label
        inCL                            matlab.ui.control.NumericEditField
        WingspanLabel_4                 matlab.ui.control.Label
        FlightConditions                matlab.ui.container.Panel
        FlightConditionsLabel           matlab.ui.control.Label
        inDynamicPressure               matlab.ui.control.NumericEditField
        AspectRatioLabel_2              matlab.ui.control.Label
        inFreeVelocity                  matlab.ui.control.NumericEditField
        MeanAerodynamicChordLabel_2     matlab.ui.control.Label
        inAirDensity                    matlab.ui.control.NumericEditField
        WingspanLabel_3                 matlab.ui.control.Label
        AircraftGeometry                matlab.ui.container.Panel
        AG_CheckBox_1                   matlab.ui.control.CheckBox
        AG_CheckBox_2                   matlab.ui.control.CheckBox
        AG_CheckBox_3                   matlab.ui.control.CheckBox
        AG_CheckBox_4                   matlab.ui.control.CheckBox
        AircraftGeometryLabel           matlab.ui.control.Label
        inWingspan                      matlab.ui.control.NumericEditField
        inMeanChord                     matlab.ui.control.NumericEditField
        inAspectRatio                   matlab.ui.control.NumericEditField
        inWingArea                      matlab.ui.control.NumericEditField
        WingspanLabel_2                 matlab.ui.control.Label
        AspectRatioLabel                matlab.ui.control.Label
        MeanAerodynamicChordLabel       matlab.ui.control.Label
        WingspanLabel                   matlab.ui.control.Label
        PerformanceHeader               matlab.ui.control.Label
    end

    
    properties (Access = private)
        % CSAT
        inVector = [0;0;0];
        outVector = [0;0;0];

        inCS = 1;
        outCS = 1;

        roll = 0;
        pitch = 0;
        yaw = 0;
        alpha = 0;
        beta = 0;
        gamma1 = 0;
        gamma2 = 0;
        gamma3 = 0;

        % ISA
        geopotentialAltitude = 0 % Geopotential Altitude
        temperature = 288.15;
        speedOfSound = 340.2940;
        pressure = 101325;
        density = 1.2250;
        viscosity = 1.4607e-05;

        % Performace (USE _P to denote performance tab variables)

        % Aircraft Geometry
        geoChecks = 2;

    end
    
    methods (Access = private)
        
        function transformCS(app)
            tV = app.inVector;
            tCS = app.inCS;
            while tCS ~= app.outCS
                
                if tCS == 1
                    tV =  [1,0,0;0, cos(app.roll), sin(app.roll);0,-sin(app.roll), cos(app.roll)] *  [
                        cos(app.pitch), 0, -sin(app.pitch);0,1,0;sin(app.pitch),0,cos(app.pitch)] * [
                        cos(app.yaw),sin(app.yaw),0; -sin(app.yaw), cos(app.yaw), 0;0,0,1;] * tV;
                elseif tCS == 2
                    tV = [cos(app.alpha), 0, sin(app.alpha);0,1,0;-sin(app.alpha), 0, cos(app.alpha)] * tV;
                elseif tCS == 3
                    tV = [cos(app.beta), sin(app.beta), 0;-sin(app.beta),cos(app.beta),0;0, 0, 1] * tV;
                elseif tCS == 4
                    tV = [1,0,0;0,cos(app.gamma1), -sin(app.gamma1); 0, sin(app.gamma1), cos(app.gamma1)] * tV;
                elseif tCS == 5
                    tV = [cos(app.gamma3), -sin(app.gamma3), 0; sin(app.gamma3), cos(app.gamma3), 0; 0, 0, 1] * [
                        cos(app.gamma2), 0, sin(app.gamma2); 0, 1, 0; -sin(app.gamma2), 0, cos(app.gamma2)] * tV;
                end

                tCS = tCS + 1;
                if tCS == 6
                    tCS = 1;
                end
            end

            app.outVector = tV;
            app.outXEditField.Value = tV(1);
            app.outYEditField.Value = tV(2);
            app.outZEditField.Value = tV(3);
        end
        
        function loadAtmLayers(app)
            data = {
                'Troposphere', 0, 288.15, 101325, 1.225;
                'Tropopause', 11000,216.65,22632,0.3639;
                'Stratosphere',20000,216.65,5474.9,0.0880;
                'Stratosphere',32000,228.65,868.02,0.0132;
                'Stratopause', 47000,270.65,110.91,0.0014;
                'Mesosphere', 51000, 270.65,66.939,0.009;
                'Mesosphere', 71000, 214.65,3.9564,0.0001;
                'Mesopause', 85000, 189.95, 0, 0};
            app.AtmLayers.Data = data;
        end
        
        function applyInputSafegaurds(app)
            if app.inCS == app.outCS
                app.RollSpinner.Enable = false;
                app.PitchSpinner.Enable = false;
                app.YawSpinner.Enable = false;
                app.AlphaSpinner.Enable = false;
                app.BetaSpinner.Enable = false;
                app.Gamma1Spinner.Enable = false;
                app.Gamma2Spinner.Enable = false;
                app.Gamma3Spinner.Enable = false;
                return
            end
            tCS = app.inCS;
            while tCS ~= app.outCS             
                if tCS == 1
                    app.RollSpinner.Enable = true;
                    app.PitchSpinner.Enable = true;
                    app.YawSpinner.Enable = true;
                elseif tCS == 2
                    app.AlphaSpinner.Enable = true;
                elseif tCS == 3
                    app.BetaSpinner.Enable = true;
                elseif tCS == 4
                    app.Gamma1Spinner.Enable = true;
                elseif tCS == 5
                    app.Gamma2Spinner.Enable = true;
                    app.Gamma3Spinner.Enable = true;
                end

                tCS = tCS + 1;
                if tCS == 6
                    tCS = 1;
                end
            end

            tCS = app.outCS;
            while tCS ~= app.inCS
                
                if tCS == 1
                    app.RollSpinner.Enable = false;
                    app.PitchSpinner.Enable = false;
                    app.YawSpinner.Enable = false;
                elseif tCS == 2
                    app.AlphaSpinner.Enable = false;
                elseif tCS == 3
                    app.BetaSpinner.Enable = false;
                elseif tCS == 4
                    app.Gamma1Spinner.Enable = false;
                elseif tCS == 5
                    app.Gamma2Spinner.Enable = false;
                    app.Gamma3Spinner.Enable = false;
                end

                tCS = tCS + 1;
                if tCS == 6
                    tCS = 1;
                end
            end
        end
        
        function computeFlightConditions(app)
            app.inDynamicPressure.Value = 0.5 * app.inAirDensity.Value * (app.inFreeVelocity.Value^2);          
        end
        
        function allGeoChecksEnable(app)
            app.AG_CheckBox_1.Enable = true;
            app.AG_CheckBox_2.Enable = true;
            app.AG_CheckBox_3.Enable = true;
            app.AG_CheckBox_4.Enable = true;
            
            app.inWingspan.Enable = false;
            app.inMeanChord.Enable = false;
            app.inAspectRatio.Enable = false;
            app.inWingArea.Enable = false;
        end
        
        function recomputeGeoChecks(app, value)

            if value == 1
                app.geoChecks = app.geoChecks + 1;
            else
                app.geoChecks = app.geoChecks - 1;     
                app.allGeoChecksEnable();
                return
            end

            if app.geoChecks == 2
                if(app.AG_CheckBox_1.Value == 0)
                    app.inWingspan.Enable = false;
                    app.AG_CheckBox_1.Enable = false;
                end
                if(app.AG_CheckBox_2.Value == 0)
                    app.inMeanChord.Enable = false;
                    app.AG_CheckBox_2.Enable = false;
                end
                if(app.AG_CheckBox_3.Value == 0)
                    app.inAspectRatio.Enable = false;
                    app.AG_CheckBox_3.Enable = false;
                end
                if(app.AG_CheckBox_4.Value == 0)
                    app.inWingArea.Enable = false;
                    app.AG_CheckBox_4.Enable = false;
                end

                app.inWingspan.Enable = app.AG_CheckBox_1.Value;
                app.inMeanChord.Enable = app.AG_CheckBox_2.Value;
                app.inAspectRatio.Enable = app.AG_CheckBox_3.Value;
                app.inWingArea.Enable = app.AG_CheckBox_4.Value;

            end
        end

        
        function updateAircraftGeometry(app)
            try
            cb1 = app.AG_CheckBox_1.Value;
            cb2 = app.AG_CheckBox_2.Value;
            cb3 = app.AG_CheckBox_3.Value;
            cb4 = app.AG_CheckBox_4.Value;
            if cb1 && cb2
                app.inWingArea.Value = app.inWingspan.Value * app.inMeanChord.Value;
                app.inAspectRatio.Value = app.inWingspan.Value / app.inMeanChord.Value;
            elseif cb1 && cb3
                app.inMeanChord.Value = app.inWingspan.Value / app.inAspectRatio.Value;
                app.inWingArea.Value = app.inWingspan.Value * app.inMeanChord.Value;
            elseif cb1 && cb4
                app.inMeanChord.Value = app.inWingArea.Value / app.inWingspan.Value;
                app.inAspectRatio.Value = app.inWingspan.Value / app.inMeanChord.Value;
            elseif cb2 && cb3
                app.inWingspan.Value = app.inMeanChord.Value * app.inAspectRatio.Value;
                app.inWingArea.Value = app.inWingspan.Value * app.inMeanChord.Value;
            elseif cb2 && cb4
                app.inWingspan.Value = app.inWingArea.Value / app.inMeanChord.Value;
                app.inAspectRatio.Value = app.inWingspan.Value / app.inMeanChord.Value;
            elseif cb3 && cb4
                app.inWingspan.Value = sqrt(app.inAspectRatio.Value * app.inWingArea.Value);
                app.inMeanChord.Value = app.inWingspan.Value / app.inAspectRatio.Value;
            end
            updateAerodynamicCoeficients(app);
            catch
            end
        end
        
        function updateAerodynamicCoeficients(app)
            try
                app.inK.Value = 1 / (pi * app.inOswald.Value * app.inAspectRatio.Value);
            catch
            end

            try
                app.inCD.Value = app.inCD0.Value + (app.inK.Value * (app.inCL.Value^2));
            catch
            end
            updateCalculatedParameters(app);
        end
        
        function updateCalculatedParameters(app)
          
            if app.OverideParametersSwitch.Value == "On"
                updateCruisePerformance(app);
                updateClimbPerformance(app);
                updateGlidePerformance(app);
                return
            end  

            try
                app.inLift.Value = app.inCL.Value * app.inWingArea.Value * app.inDynamicPressure.Value;
                app.inDrag.Value = app.inCD.Value * app.inWingArea.Value * app.inDynamicPressure.Value;
                app.inWeight.Value = app.inLift.Value;
                app.inThrust.Value = app.inDrag.Value;
                app.inInitialMass.Value = app.inWeight.Value / 9.8;
                app.inFinalMass.Value = app.inInitialMass.Value - app.inFuelMass.Value;
            catch
            end    

            try
                app.inLD.Value = app.inLift.Value / app.inDrag.Value;
            catch
            end

            try
                app.inMaxLD.Value = 1 / sqrt(4 * app.inCD0.Value * app.inK.Value);
            catch
            end

            try
                app.inThrustToWeight.Value = app.inThrust.Value / app.inWeight.Value;
            catch
            end

            try
                app.inWingLoading.Value = app.inWeight.Value / app.inWingArea.Value;
            catch
            end
            
            updateCruisePerformance(app);
            updateClimbPerformance(app);
            updateGlidePerformance(app);
        end
        
        function updateCruisePerformance(app)
            %% Jet Range
            try
                app.inJetRange.Value = (2 / (9.8 * app.inTSFC.Value)) * sqrt(2 / (app.inAirDensity.Value * app.inWingArea.Value)) * ((app.inCL.Value^0.5)/app.inCD.Value) * (sqrt(app.inInitialMass.Value  * 9.8)-sqrt(app.inFinalMass.Value * 9.8));
            catch
            end

            %% Jet Endurance
            try
                app.inJetEndurance.Value = (1 / (9.8 * app.inTSFC.Value)) * app.inLD.Value * log(app.inInitialMass.Value / app.inFinalMass.Value) / 3600;
            catch
            end
            
            %% Propeller Range
            try
                app.inPropRange.Value = (app.inPropEfficiency.Value / ((9.8 * app.inTSFC.Value) / app.inFreeVelocity.Value)) * app.inLD.Value * log(app.inInitialMass.Value / app.inFinalMass.Value);
            catch
            end

            %% Propeller Endurance
            try
                app.inPropEndurance.Value = (app.inPropEfficiency.Value / ((9.8 * app.inTSFC.Value) / app.inFreeVelocity.Value)) * sqrt(2 * app.inAirDensity.Value * app.inWingArea.Value) * ((app.inCL.Value^1.5)/app.inCD.Value)* ((1/sqrt(app.inFinalMass.Value  * 9.8))-(1/sqrt(app.inInitialMass.Value * 9.8))) / 3600;
            catch
            end
        end
        
        function updateClimbPerformance(app)
            %% JET
            try
                z = 1 + sqrt(1 + (3 / (app.inMaxLD.Value^2 * app.inClimbThrustToWeight.Value^2)));
                app.inJetRCMax.Value = sqrt((app.inClimbWingLoading.Value * z) / (3 * app.inAirDensity.Value * app.inCD0.Value)) * (app.inClimbThrustToWeight.Value ^ (3/2)) * (1 - (z/6) - (3/(2*z*(app.inClimbThrustToWeight.Value^2)*(app.inMaxLD.Value^2))));
                app.inJetVRCMax.Value = sqrt(((app.inClimbThrustToWeight.Value*app.inClimbWingLoading.Value)/(3 * app.inAirDensity.Value * app.inCD0.Value)) * (1 + sqrt(1 + (3 / ((app.inClimbThrustToWeight.Value^2)*(app.inMaxLD.Value^2))))));
            catch
            end

            try
                app.inJetThetaMax.Value = rad2deg(asin(app.inClimbThrustToWeight.Value - sqrt(4 * app.inCD0.Value * app.inK.Value)));
            catch
            end

            try
                app.inJetVThetaMax.Value = sqrt((2/app.inAirDensity.Value)*sqrt(app.inK.Value/app.inCD0.Value)* app.inClimbWingLoading.Value*cos(deg2rad(app.inJetThetaMax.Value)));
            catch
            end

            try
                app.inJetThetaRCMax.Value = rad2deg(asin(app.inJetRCMax.Value / app.inJetVRCMax.Value));
            catch
            end
            
            %% PROP
            try

                app.inPropRCMax.Value = (((app.inPropEfficiency.Value * app.inPropPower.Value * (0.7375) ) / (app.inClimbWeight.Value * 0.224)) - (sqrt((2/(app.inAirDensity.Value*0.00194)) - (sqrt(app.inK.Value/(3*app.inCD0.Value))*(app.inClimbWingLoading.Value*0.020885))) * (1.155 / app.inMaxLD.Value)))* 0.3048;
            
            catch            
            end

            try
                z = 1 + sqrt(1 + (3 / (app.inMaxLD.Value^2 * app.inClimbThrustToWeight.Value^2)));
                app.inPropVRCMax.Value = sqrt((z/app.inAirDensity.Value) * (sqrt(app.inK.Value/(3*app.inCD0.Value))*app.inClimbWingLoading.Value));
            catch             
            end

            try
                %app.inPropThetaMax.Value = rad2deg(asin( ((app.inPropEfficiency.Value * app.inPropPower.Value) / (app.inFreeVelocity.Value * app.inClimbWeight.Value)) - (app.inDynamicPressure.Value*(1/app.inClimbWingLoading.Value) * app.inCD0.Value) - (app.inClimbWingLoading.Value * ((2 * app.inK.Value) / (2 * app.inDynamicPressure.Value))) ));    
                app.inPropThetaMax.Value = rad2deg(asin((app.inClimbThrustToWeight.Value*app.inPropEfficiency.Value) - sqrt(4 * app.inCD0.Value * app.inK.Value)));
            catch             
            end

            try
                 %app.inPropVThetaMax.Value = ((4 * app.inClimbWingLoading.Value * app.inK.Value)/(app.inAirDensity.Value * app.inPropEfficiency.Value * (app.inPropPower.Value/app.inClimbWeight.Value))) * (((app.inPropPower.Value * app.inPropEfficiency.Value)/(app.inClimbWeight.Value * app.inFreeVelocity.Value)) - (app.inDynamicPressure.Value * (1/app.inClimbWingLoading.Value) * app.inCD0.Value) - (app.inClimbWingLoading.Value * ((2 * app.inK.Value) / (2 * app.inDynamicPressure.Value))));
                 app.inPropVThetaMax.Value = app.inPropEfficiency.Value * sqrt((2/app.inAirDensity.Value)*sqrt(app.inK.Value/app.inCD0.Value)* app.inClimbWingLoading.Value*cos(deg2rad(app.inPropThetaMax.Value)));
            catch             
            end
            try
                app.inPropThetaRCMax.Value = rad2deg(asin(app.inPropRCMax.Value / app.inPropVRCMax.Value));
            catch
            end

        end
        
        function updateGlidePerformance(app)
            try
                app.inMinGlideAngle.Value = rad2deg(atan(1/app.inMaxLD.Value));
            catch
            end
            
            try
                app.inDescentRate.Value = sqrt(2/app.inAirDensity.Value) * sqrt(app.inWeight.Value/app.inWingArea.Value) * ((4 * app.inCD0.Value) / (((3*app.inCD0.Value)/app.inK.Value)^(3/4)));
            catch
            end
            
            try
                app.inGlidingTime.Value = (app.inInitHeight.Value / app.inDescentRate.Value) / 3600;
            catch
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GeopotentialAltitudemEditFieldValueChanged(app);
            loadAtmLayers(app);
            applyInputSafegaurds(app);
        end

        % Value changed function: RollSpinner
        function RollSpinnerValueChanged(app, event)
            value = app.RollSpinner.Value;

            if value < -180
                value = -180;
                app.RollSpinner.Value = value;
            end
            if value > 180
                value = 180;
                app.RollSpinner.Value = value;
            end

            app.ArtificialHorizon.Roll = value;  
            app.roll = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: PitchSpinner
        function PitchSpinnerValueChanged(app, event)
            value = app.PitchSpinner.Value;

            if value < -90
                value = -90;
                app.PitchSpinner.Value = value;
            end
            if value > 90
                value = 90;
                app.PitchSpinner.Value = value;
            end

            app.ArtificialHorizon.Pitch = value;
            app.pitch = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: YawSpinner
        function YawSpinnerValueChanged(app, event)
            value = app.YawSpinner.Value;

            if value < 0
                value = 0;
                app.YawSpinner.Value = value;
            end
            if value > 360
                value = 360;
                app.YawSpinner.Value = value;
            end

            app.yaw = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: GeopotentialAltitudemEditField
        function GeopotentialAltitudemEditFieldValueChanged(app, event)
            value = app.GeopotentialAltitudemEditField.Value;
            if value < -5000
                value = -5000;
            end
            if value > 84852
                value = 84852;
            end
            app.GeopotentialAltitudemEditField.Value = value;
            app.geopotentialAltitude = value;

            [app.temperature, app.speedOfSound, app.pressure, app.density, app.viscosity] = atmosisa(value, 'extended', true);
            app.TemperatureKEditField.Value = app.temperature;
            app.SpeedofSoundmsEditField.Value = app.speedOfSound;
            app.PressurePaEditField.Value = app.pressure;
            app.Densitykgm3EditField.Value = app.density;
            app.Viscositym2sEditField.Value = app.viscosity;

            altitude = linspace(0, 80000, 1000);
            [T, A, P, p, NU] = atmosisa(altitude, 'extended', true);
            plot(app.TemperatureAltitudeAxis, T, altitude);
            axis(app.TemperatureAltitudeAxis, 'auto');
            line(app.TemperatureAltitudeAxis, [app.temperature, app.temperature], ylim(app.TemperatureAltitudeAxis), 'Color', 'r', 'LineStyle', '--');
            line(app.TemperatureAltitudeAxis, xlim(app.TemperatureAltitudeAxis), [app.geopotentialAltitude, app.geopotentialAltitude], 'Color', 'r', 'LineStyle', '-.');

            plot(app.PressureAltitudeAxis, P, altitude);
            axis(app.PressureAltitudeAxis, 'auto');
            line(app.PressureAltitudeAxis, [app.pressure, app.pressure], ylim(app.PressureAltitudeAxis), 'Color', 'r', 'LineStyle', '--');
            line(app.PressureAltitudeAxis, xlim(app.PressureAltitudeAxis), [app.geopotentialAltitude, app.geopotentialAltitude], 'Color', 'r', 'LineStyle', '-.');

            plot(app.DensityAltitudeAxis, p, altitude);
            axis(app.DensityAltitudeAxis, 'auto');
            line(app.DensityAltitudeAxis, [app.density, app.density], ylim(app.DensityAltitudeAxis), 'Color', 'r', 'LineStyle', '--');
            line(app.DensityAltitudeAxis, xlim(app.DensityAltitudeAxis), [app.geopotentialAltitude, app.geopotentialAltitude], 'Color', 'r', 'LineStyle', '-.');

            plot(app.SpeedAltitudeAxis, A, altitude);
            axis(app.SpeedAltitudeAxis, 'auto');
            line(app.SpeedAltitudeAxis, [app.speedOfSound, app.speedOfSound], ylim(app.SpeedAltitudeAxis), 'Color', 'r', 'LineStyle', '--');
            line(app.SpeedAltitudeAxis, xlim(app.SpeedAltitudeAxis), [app.geopotentialAltitude, app.geopotentialAltitude], 'Color', 'r', 'LineStyle', '-.');

            plot(app.ViscosityAltitudeAxis, NU, altitude);
            axis(app.ViscosityAltitudeAxis, 'auto');
            line(app.ViscosityAltitudeAxis, [app.viscosity, app.viscosity], ylim(app.ViscosityAltitudeAxis), 'Color', 'r', 'LineStyle', '--');
            line(app.ViscosityAltitudeAxis, xlim(app.ViscosityAltitudeAxis), [app.geopotentialAltitude, app.geopotentialAltitude], 'Color', 'r', 'LineStyle', '-.');
            

        end

        % Value changed function: TemperatureKEditField
        function TemperatureKEditFieldValueChanged(app, event)
            GeopotentialAltitudemEditFieldValueChanged(app, event);
        end

        % Value changed function: SpeedofSoundmsEditField
        function SpeedofSoundmsEditFieldValueChanged(app, event)
           GeopotentialAltitudemEditFieldValueChanged(app, event);
            
        end

        % Value changed function: PressurePaEditField
        function PressurePaEditFieldValueChanged(app, event)
            GeopotentialAltitudemEditFieldValueChanged(app, event);
            
        end

        % Value changed function: Densitykgm3EditField
        function Densitykgm3EditFieldValueChanged(app, event)
            GeopotentialAltitudemEditFieldValueChanged(app, event);
            
        end

        % Value changed function: Viscositym2sEditField
        function Viscositym2sEditFieldValueChanged(app, event)
            GeopotentialAltitudemEditFieldValueChanged(app, event);
            
        end

        % Value changed function: inXEditField
        function inXEditFieldValueChanged(app, event)
            value = app.inXEditField.Value;
            app.inVector(1) = value;
            transformCS(app);
        end

        % Value changed function: inYEditField
        function inYEditFieldValueChanged(app, event)
            value = app.inYEditField.Value;
            app.inVector(2) = value;
            transformCS(app);
        end

        % Value changed function: inZEditField
        function inZEditFieldValueChanged(app, event)
            value = app.inZEditField.Value;
            app.inVector(3) = value;
            transformCS(app);
        end

        % Value changed function: CoordinateSystemInDropDown
        function CoordinateSystemInDropDownValueChanged(app, event)
            value = app.CoordinateSystemInDropDown.Value;
            if value == "Earth"
                app.inCS = 1;
            elseif  value == "Body"
                app.inCS = 2;
            elseif value == "Stability"
                app.inCS = 3;
            elseif value == "Wind"
                app.inCS = 4;
            else
                app.inCS = 5;
            end
            transformCS(app)
            applyInputSafegaurds(app);
        end

        % Value changed function: CoordinateSystemOutDropDown
        function CoordinateSystemOutDropDownValueChanged(app, event)
            value = app.CoordinateSystemOutDropDown.Value;
            if value == "Earth"
                app.outCS = 1;
            elseif  value == "Body"
                app.outCS = 2;
            elseif value == "Stability"
                app.outCS = 3;
            elseif value == "Wind"
                app.outCS = 4;
            else
                app.outCS = 5;
            end
            transformCS(app)
            applyInputSafegaurds(app);
        end

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            value = app.AlphaSpinner.Value;
            app.alpha = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: BetaSpinner
        function BetaSpinnerValueChanged(app, event)
            value = app.BetaSpinner.Value;
            app.beta = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: Gamma1Spinner
        function Gamma1SpinnerValueChanged(app, event)
            value = app.Gamma1Spinner.Value;
            app.gamma1 = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: Gamma2Spinner
        function Gamma2SpinnerValueChanged(app, event)
            value = app.Gamma2Spinner.Value;
            app.gamma2 = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: Gamma3Spinner
        function Gamma3SpinnerValueChanged(app, event)
            value = app.Gamma3Spinner.Value;
            app.gamma3 = deg2rad(value);
            transformCS(app);
        end

        % Value changed function: inAirDensity
        function inAirDensityValueChanged(app, event)
            computeFlightConditions(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inFreeVelocity
        function inFreeVelocityValueChanged(app, event)
            computeFlightConditions(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inWingspan
        function inWingspanValueChanged(app, event)
            updateAircraftGeometry(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inMeanChord
        function inMeanChordValueChanged(app, event)
            updateAircraftGeometry(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inAspectRatio
        function inAspectRatioValueChanged(app, event)
            updateAircraftGeometry(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inWingArea
        function inWingAreaValueChanged(app, event)
            updateAircraftGeometry(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: AG_CheckBox_1
        function AG_CheckBox_1ValueChanged(app, event)
            value = app.AG_CheckBox_1.Value;
            recomputeGeoChecks(app, value)         
        end

        % Value changed function: AG_CheckBox_2
        function AG_CheckBox_2ValueChanged(app, event)
            value = app.AG_CheckBox_2.Value;
            recomputeGeoChecks(app, value)       
        end

        % Value changed function: AG_CheckBox_3
        function AG_CheckBox_3ValueChanged(app, event)
            value = app.AG_CheckBox_3.Value;
            recomputeGeoChecks(app, value)       
        end

        % Value changed function: AG_CheckBox_4
        function AG_CheckBox_4ValueChanged(app, event)
            value = app.AG_CheckBox_4.Value;
            recomputeGeoChecks(app, value)       
        end

        % Value changed function: inCL
        function inCLValueChanged(app, event)
            updateAerodynamicCoeficients(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inCD0
        function inCD0ValueChanged(app, event)
            updateAerodynamicCoeficients(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inOswald
        function inOswaldValueChanged(app, event)
            updateAerodynamicCoeficients(app);
            updateCalculatedParameters(app);
        end

        % Value changed function: inFuelMass
        function inFuelMassValueChanged(app, event)
            updateCalculatedParameters(app);            
        end

        % Value changed function: inTSFC
        function inTSFCValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: OverideParametersSwitch
        function OverideParametersSwitchValueChanged(app, event)

            val = false;
            if app.OverideParametersSwitch.Value == "On"
                val = true;
            end
            
            app.inLift.Editable = val;
            app.inWeight.Editable = val;
            app.inDrag.Editable = val;
            app.inThrust.Editable = val;
            app.inInitialMass.Editable = val;
            app.inFinalMass.Editable = val;
            app.inLD.Editable = val;
            app.inMaxLD.Editable = val;
            app.inThrustToWeight.Editable = val;
            app.inWingLoading.Editable = val;

            updateCalculatedParameters(app);
        end

        % Value changed function: inPropEfficiency
        function inPropEfficiencyValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inLift
        function inLiftValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inWeight
        function inWeightValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inDrag
        function inDragValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inThrust
        function inThrustValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inInitialMass
        function inInitialMassValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inFinalMass
        function inFinalMassValueChanged(app, event)
           updateCalculatedParameters(app);
        end

        % Value changed function: inLD
        function inLDValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inMaxLD
        function inMaxLDValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inThrustToWeight
        function inThrustToWeightValueChanged(app, event)
           updateCalculatedParameters(app);  
        end

        % Value changed function: inWingLoading
        function inWingLoadingValueChanged(app, event)
            updateCalculatedParameters(app);
        end

        % Value changed function: inClimbWeight
        function inClimbWeightValueChanged(app, event)
            updateClimbPerformance(app);
            
        end

        % Value changed function: inClimbThrustToWeight
        function inClimbThrustToWeightValueChanged(app, event)
           updateClimbPerformance(app);
            
        end

        % Value changed function: inClimbWingLoading
        function inClimbWingLoadingValueChanged(app, event)
           updateClimbPerformance(app);    
        end

        % Value changed function: inPropPower
        function inPropPowerValueChanged(app, event)
            updateClimbPerformance(app);    
        end

        % Value changed function: inInitHeight
        function inInitHeightValueChanged(app, event)
            updateGlidePerformance(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ISATab = uitab(app.TabGroup);
            app.ISATab.Title = 'ISA';
            app.TemperatureAltitudeAxis = uiaxes(app.ISATab);
            title(app.TemperatureAltitudeAxis, 'Temperature Altitude')
            xlabel(app.TemperatureAltitudeAxis, 'Temperature (K)')
            ylabel(app.TemperatureAltitudeAxis, 'Geopotential Altitude (m)')
            zlabel(app.TemperatureAltitudeAxis, 'Z')
            app.PressureAltitudeAxis = uiaxes(app.ISATab);
            title(app.PressureAltitudeAxis, 'Pressure Altitude')
            xlabel(app.PressureAltitudeAxis, 'Pressure (Pa)')
            ylabel(app.PressureAltitudeAxis, 'Geopotential Altitude (m)')
            zlabel(app.PressureAltitudeAxis, 'Z')
            app.DensityAltitudeAxis = uiaxes(app.ISATab);
            title(app.DensityAltitudeAxis, 'Density Altitude')
            xlabel(app.DensityAltitudeAxis, 'Density (kg/m^3)')
            ylabel(app.DensityAltitudeAxis, 'Geopotential Altitude (m)')
            zlabel(app.DensityAltitudeAxis, 'Z')
            app.SpeedAltitudeAxis = uiaxes(app.ISATab);
            title(app.SpeedAltitudeAxis, 'Speed of Sound vs. Altitude')
            xlabel(app.SpeedAltitudeAxis, 'Speed of Sound (m/s)')
            ylabel(app.SpeedAltitudeAxis, 'Geopotential Altitude (m)')
            zlabel(app.SpeedAltitudeAxis, 'Z')
            app.ViscosityAltitudeAxis = uiaxes(app.ISATab);
            title(app.ViscosityAltitudeAxis, 'Kinematic Viscosity vs. Altitude')
            xlabel(app.ViscosityAltitudeAxis, 'Kinematic Viscosity (m^2/s)')
            ylabel(app.ViscosityAltitudeAxis, 'Geopotential Altitude (m)')
            zlabel(app.ViscosityAltitudeAxis, 'Z')
            app.GeopotentialAltitudemEditFieldLabel = uilabel(app.ISATab);
            app.GeopotentialAltitudemEditFieldLabel.Text = 'Geopotential Altitude (m)';
            app.TemperatureKEditFieldLabel = uilabel(app.ISATab);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.SpeedofSoundmsEditFieldLabel = uilabel(app.ISATab);
            app.SpeedofSoundmsEditFieldLabel.Text = 'Speed of Sound (m/s)';
            app.PressurePaEditFieldLabel = uilabel(app.ISATab);
            app.PressurePaEditFieldLabel.Text = 'Pressure (Pa)';
            app.Densitykgm3EditFieldLabel = uilabel(app.ISATab);
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m^3)';
            app.Viscositym2sEditFieldLabel = uilabel(app.ISATab);
            app.Viscositym2sEditFieldLabel.Text = 'Viscosity (m^2/s)';
            app.Viscositym2sEditField = uieditfield(app.ISATab, 'numeric');
            app.Viscositym2sEditField.ValueChangedFcn = createCallbackFcn(app, @Viscositym2sEditFieldValueChanged, true);
            app.Viscositym2sEditField.Editable = 'off';
            app.Viscositym2sEditField.Value = 1.4607e-05;
            app.Densitykgm3EditField = uieditfield(app.ISATab, 'numeric');
            app.Densitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @Densitykgm3EditFieldValueChanged, true);
            app.Densitykgm3EditField.Editable = 'off';
            app.Densitykgm3EditField.Value = 1.225;
            app.PressurePaEditField = uieditfield(app.ISATab, 'numeric');
            app.PressurePaEditField.ValueChangedFcn = createCallbackFcn(app, @PressurePaEditFieldValueChanged, true);
            app.PressurePaEditField.Editable = 'off';
            app.PressurePaEditField.Value = 101325;
            app.SpeedofSoundmsEditField = uieditfield(app.ISATab, 'numeric');
            app.SpeedofSoundmsEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedofSoundmsEditFieldValueChanged, true);
            app.SpeedofSoundmsEditField.Editable = 'off';
            app.SpeedofSoundmsEditField.Value = 340.294;
            app.TemperatureKEditField = uieditfield(app.ISATab, 'numeric');
            app.TemperatureKEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureKEditFieldValueChanged, true);
            app.TemperatureKEditField.Editable = 'off';
            app.TemperatureKEditField.Value = 288.15;
            app.GeopotentialAltitudemEditField = uieditfield(app.ISATab, 'numeric');
            app.GeopotentialAltitudemEditField.ValueChangedFcn = createCallbackFcn(app, @GeopotentialAltitudemEditFieldValueChanged, true);
            app.Label = uilabel(app.ISATab);
            app.Label.Text = {'The International Standard Atmosphere (ISA) is an atmospheric model published by the International Organization for Standardization (ISO). '; 'The model is an approximation of pressure, density, temperature, speed of sound, and viscosity at geopotential altitudes up to 84,852 meters.'; 'This application is an interface for the ISA model which helps visualize the relationships between atmospheric parameters.'};
            app.TabGroup2 = uitabgroup(app.ISATab);
            app.AtmosphereLayersTab = uitab(app.TabGroup2);
            app.AtmosphereLayersTab.Title = 'Atmosphere Layers';
            app.AtmLayers = uitable(app.AtmosphereLayersTab);
            app.AtmLayers.ColumnName = {'Layer Name'; 'Base Geopotential Altitude Above MSL (m)'; 'Base Temperature (K)'; 'Base Pressure (Pa)'; 'Base Density (kg/m^3)'};
            app.AtmLayers.RowName = {};
            app.AdditionalInformationTab = uitab(app.TabGroup2);
            app.AdditionalInformationTab.Title = 'Additional Information';
            app.TheEarthsatmosphereiscomprisedofLabel = uilabel(app.AdditionalInformationTab);
            app.TheEarthsatmosphereiscomprisedofLabel.Text = {'Earth''s atmosphere consists of five primary layers: the troposphere, stratosphere, mesosphere, thermosphere, and exosphere. '; 'The troposphere is the first layer and spans from sea level to 11,000 meters. Most commercial aircraft cruise in the upper '; 'troposphere or in the lower stratosphere, the second layer. In the lower stratosphere temperature remains at a constant 216.65 K; '; 'however, temperature proceeds to increase in the upper stratosphere. The third layer is the mesosphere and begins at 51,000 '; 'meters; here temperature begins to decrease as altitudes reach the thermosphere. In the thermosphere, temperature continues '; 'to decrease and pressure and density decrease rapidly towards zero. For more details see the Atmosphere Layers tab.'};
            app.DesignedbyRyanKing0959315Label_2 = uilabel(app.ISATab);
            app.DesignedbyRyanKing0959315Label_2.Text = 'Designed by Ryan King (#0959315)';
            app.CSATTab = uitab(app.TabGroup);
            app.CSATTab.Title = 'CSAT';
            app.AircraftOrientationAnglesDegreesPanel = uipanel(app.CSATTab);
            app.AircraftOrientationAnglesDegreesPanel.Title = 'Aircraft Orientation Angles (Degrees)';
            app.RollSpinnerLabel = uilabel(app.AircraftOrientationAnglesDegreesPanel);
            app.RollSpinnerLabel.Text = 'Roll';
            app.PitchSpinnerLabel = uilabel(app.AircraftOrientationAnglesDegreesPanel);
            app.PitchSpinnerLabel.Text = 'Pitch';
            app.YawSpinnerLabel = uilabel(app.AircraftOrientationAnglesDegreesPanel);
            app.YawSpinnerLabel.Text = 'Yaw';
            app.YawSpinner = uispinner(app.AircraftOrientationAnglesDegreesPanel);
            app.YawSpinner.ValueChangedFcn = createCallbackFcn(app, @YawSpinnerValueChanged, true);
            app.PitchSpinner = uispinner(app.AircraftOrientationAnglesDegreesPanel);
            app.PitchSpinner.ValueChangedFcn = createCallbackFcn(app, @PitchSpinnerValueChanged, true);
            app.RollSpinner = uispinner(app.AircraftOrientationAnglesDegreesPanel);
            app.RollSpinner.ValueChangedFcn = createCallbackFcn(app, @RollSpinnerValueChanged, true);
            app.ArtificialHorizon = uiaerohorizon(app.AircraftOrientationAnglesDegreesPanel);
            app.PanelIn = uipanel(app.CSATTab);
            app.InputCoordinateSystemLabel = uilabel(app.PanelIn);
            app.InputCoordinateSystemLabel.Text = 'Input Coordinate System';
            app.CoordinateSystemInDropDown = uidropdown(app.PanelIn);
            app.CoordinateSystemInDropDown.Items = {'Earth', 'Body', 'Stability', 'Wind', 'Velocity'};
            app.CoordinateSystemInDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemInDropDownValueChanged, true);
            app.CoordinateSystemInDropDown.Value = 'Earth';
            app.XEditFieldLabel = uilabel(app.PanelIn);
            app.XEditFieldLabel.Text = 'X';
            app.YEditFieldLabel = uilabel(app.PanelIn);
            app.YEditFieldLabel.Text = 'Y';
            app.inZEditField = uieditfield(app.PanelIn, 'numeric');
            app.inZEditField.ValueChangedFcn = createCallbackFcn(app, @inZEditFieldValueChanged, true);
            app.inYEditField = uieditfield(app.PanelIn, 'numeric');
            app.inYEditField.ValueChangedFcn = createCallbackFcn(app, @inYEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.PanelIn);
            app.ZEditFieldLabel.Text = 'Z';
            app.inXEditField = uieditfield(app.PanelIn, 'numeric');
            app.inXEditField.ValueChangedFcn = createCallbackFcn(app, @inXEditFieldValueChanged, true);
            app.PanelOut = uipanel(app.CSATTab);
            app.OutputCoordinateSystemLabel = uilabel(app.PanelOut);
            app.OutputCoordinateSystemLabel.Text = 'Output Coordinate System';
            app.CoordinateSystemOutDropDown = uidropdown(app.PanelOut);
            app.CoordinateSystemOutDropDown.Items = {'Earth', 'Body', 'Stability', 'Wind', 'Velocity'};
            app.CoordinateSystemOutDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemOutDropDownValueChanged, true);
            app.CoordinateSystemOutDropDown.Value = 'Earth';
            app.XEditField_2Label = uilabel(app.PanelOut);
            app.XEditField_2Label.Text = 'X';
            app.YEditField_2Label = uilabel(app.PanelOut);
            app.YEditField_2Label.Text = 'Y';
            app.ZEditField_2Label = uilabel(app.PanelOut);
            app.ZEditField_2Label.Text = 'Z';
            app.outZEditField = uieditfield(app.PanelOut, 'numeric');
            app.outZEditField.Editable = 'off';
            app.outYEditField = uieditfield(app.PanelOut, 'numeric');
            app.outYEditField.Editable = 'off';
            app.outXEditField = uieditfield(app.PanelOut, 'numeric');
            app.outXEditField.Editable = 'off';
            app.TransformationAnglesDegreesPanel = uipanel(app.CSATTab);
            app.TransformationAnglesDegreesPanel.Title = 'Transformation Angles (Degrees)';
            app.AlphaSpinnerLabel = uilabel(app.TransformationAnglesDegreesPanel);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.TransformationAnglesDegreesPanel);
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.BetaSpinnerLabel = uilabel(app.TransformationAnglesDegreesPanel);
            app.BetaSpinnerLabel.Text = 'Beta';
            app.BetaSpinner = uispinner(app.TransformationAnglesDegreesPanel);
            app.BetaSpinner.ValueChangedFcn = createCallbackFcn(app, @BetaSpinnerValueChanged, true);
            app.Gamma1SpinnerLabel = uilabel(app.TransformationAnglesDegreesPanel);
            app.Gamma1SpinnerLabel.Text = 'Gamma 1';
            app.Gamma1Spinner = uispinner(app.TransformationAnglesDegreesPanel);
            app.Gamma1Spinner.ValueChangedFcn = createCallbackFcn(app, @Gamma1SpinnerValueChanged, true);
            app.Gamma2SpinnerLabel = uilabel(app.TransformationAnglesDegreesPanel);
            app.Gamma2SpinnerLabel.Text = 'Gamma 2';
            app.Gamma2Spinner = uispinner(app.TransformationAnglesDegreesPanel);
            app.Gamma2Spinner.ValueChangedFcn = createCallbackFcn(app, @Gamma2SpinnerValueChanged, true);
            app.Gamma3SpinnerLabel = uilabel(app.TransformationAnglesDegreesPanel);
            app.Gamma3SpinnerLabel.Text = 'Gamma 3';
            app.Gamma3Spinner = uispinner(app.TransformationAnglesDegreesPanel);
            app.Gamma3Spinner.ValueChangedFcn = createCallbackFcn(app, @Gamma3SpinnerValueChanged, true);
            app.Label_2 = uilabel(app.CSATTab);
            app.Label_2.Text = {'The Coordinate Systems and Axis Transformations (CSAT) tool enables coordinate transformations between the Earth, Body, Stability, Wind and '; 'Velocity Coordinate Systems. The application uses input angles for roll, pitch, yaw, alpha, beta, etc. to perform vector rotations between each '; 'system.'};
            app.VisualGuidetoCoordinateSystemTransformationsPanel = uipanel(app.CSATTab);
            app.VisualGuidetoCoordinateSystemTransformationsPanel.Title = 'Visual Guide to Coordinate System Transformation(s)';
            app.TabGroup3 = uitabgroup(app.VisualGuidetoCoordinateSystemTransformationsPanel);
            app.EarthtoBodyTab = uitab(app.TabGroup3);
            app.EarthtoBodyTab.Title = 'Earth to Body';
            app.Image = uiimage(app.EarthtoBodyTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'res', 'earth_coordinates.png');
            app.EarthCoordinateSystemLabel = uilabel(app.EarthtoBodyTab);
            app.EarthCoordinateSystemLabel.Text = 'Earth Coordinate System';
            app.Image2 = uiimage(app.EarthtoBodyTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'roll_pitch_yaw.png');
            app.TransformedUsingRollPitchandYawLabel = uilabel(app.EarthtoBodyTab);
            app.TransformedUsingRollPitchandYawLabel.Text = 'Transformed Using Roll, Pitch and Yaw';
            app.BodyCoordinateSystemLabel = uilabel(app.EarthtoBodyTab);
            app.BodyCoordinateSystemLabel.Text = 'Body Coordinate System';
            app.Image3 = uiimage(app.EarthtoBodyTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'res', 'body_coordinates.png');
            app.BodytoStabilitytoWindTab = uitab(app.TabGroup3);
            app.BodytoStabilitytoWindTab.Title = 'Body to Stability to Wind';
            app.BodytoStabilityRotatesbyAngleAlphaasShownLabel = uilabel(app.BodytoStabilitytoWindTab);
            app.BodytoStabilityRotatesbyAngleAlphaasShownLabel.Text = {'Body to Stability Rotates over the Angle of Attack (Alpha).'; 'Stability to Wind Rotates over the Sideslip Angle (Beta).'};
            app.Image4 = uiimage(app.BodytoStabilitytoWindTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'res', 'stability_and_wind.png');
            app.WindtoVelocityTab = uitab(app.TabGroup3);
            app.WindtoVelocityTab.Title = 'Wind to Velocity';
            app.BodytoStabilityRotatesbyAngleAlphaasShownLabel_2 = uilabel(app.WindtoVelocityTab);
            app.BodytoStabilityRotatesbyAngleAlphaasShownLabel_2.Text = 'The Wind to Velocity Transformation Rotates through a Bank Angle (Gamma 1).';
            app.Image5 = uiimage(app.WindtoVelocityTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'res', 'wind_and_velocity.png');
            app.VelocitytoEarthTab = uitab(app.TabGroup3);
            app.VelocitytoEarthTab.Title = 'Velocity to Earth';
            app.BodytoStabilityRotatesbyAngleAlphaasShownLabel_3 = uilabel(app.VelocitytoEarthTab);
            app.BodytoStabilityRotatesbyAngleAlphaasShownLabel_3.Text = {'The Wind to Velocity Transformation Rotates through the Flight Path Gradient Angle (Gamma 2) '; 'and the Track Angle (Gamma 3) .'};
            app.Image6 = uiimage(app.VelocitytoEarthTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'res', 'velocity_and_earth.png');
            app.DesignedbyRyanKing0959315Label_3 = uilabel(app.CSATTab);
            app.DesignedbyRyanKing0959315Label_3.Text = 'Designed by Ryan King (#0959315)';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.PerformanceHeader = uilabel(app.PerformanceTab);
            app.PerformanceHeader.Text = {'The performance tab provides multiple tools for determining cruise, climb, and glide performance parameters. Enter your aircraft geometry '; 'and aerodynamic parameters to get started!'};
            app.AircraftGeometry = uipanel(app.PerformanceTab);
            app.WingspanLabel = uilabel(app.AircraftGeometry);
            app.WingspanLabel.Text = 'Wingspan (m)';
            app.MeanAerodynamicChordLabel = uilabel(app.AircraftGeometry);
            app.MeanAerodynamicChordLabel.Text = 'Mean Chord (m)';
            app.AspectRatioLabel = uilabel(app.AircraftGeometry);
            app.AspectRatioLabel.Text = 'Aspect Ratio';
            app.WingspanLabel_2 = uilabel(app.AircraftGeometry);
            app.WingspanLabel_2.Text = 'Wing Area (m^2)';
            app.inWingArea = uieditfield(app.AircraftGeometry, 'numeric');
            app.inWingArea.ValueChangedFcn = createCallbackFcn(app, @inWingAreaValueChanged, true);
            app.inWingArea.Enable = 'off';
            app.inAspectRatio = uieditfield(app.AircraftGeometry, 'numeric');
            app.inAspectRatio.ValueChangedFcn = createCallbackFcn(app, @inAspectRatioValueChanged, true);
            app.inAspectRatio.Enable = 'off';
            app.inMeanChord = uieditfield(app.AircraftGeometry, 'numeric');
            app.inMeanChord.ValueChangedFcn = createCallbackFcn(app, @inMeanChordValueChanged, true);
            app.inWingspan = uieditfield(app.AircraftGeometry, 'numeric');
            app.inWingspan.ValueChangedFcn = createCallbackFcn(app, @inWingspanValueChanged, true);
            app.AircraftGeometryLabel = uilabel(app.AircraftGeometry);
            app.AircraftGeometryLabel.Text = 'Aircraft Geometry';
            app.AG_CheckBox_4 = uicheckbox(app.AircraftGeometry);
            app.AG_CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @AG_CheckBox_4ValueChanged, true);
            app.AG_CheckBox_4.Enable = 'off';
            app.AG_CheckBox_3 = uicheckbox(app.AircraftGeometry);
            app.AG_CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @AG_CheckBox_3ValueChanged, true);
            app.AG_CheckBox_3.Enable = 'off';
            app.AG_CheckBox_2 = uicheckbox(app.AircraftGeometry);
            app.AG_CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AG_CheckBox_2ValueChanged, true);
            app.AG_CheckBox_2.Value = true;
            app.AG_CheckBox_1 = uicheckbox(app.AircraftGeometry);
            app.AG_CheckBox_1.ValueChangedFcn = createCallbackFcn(app, @AG_CheckBox_1ValueChanged, true);
            app.AG_CheckBox_1.Value = true;
            app.FlightConditions = uipanel(app.PerformanceTab);
            app.WingspanLabel_3 = uilabel(app.FlightConditions);
            app.WingspanLabel_3.Text = 'Air Density (kg/m^3)';
            app.inAirDensity = uieditfield(app.FlightConditions, 'numeric');
            app.inAirDensity.ValueChangedFcn = createCallbackFcn(app, @inAirDensityValueChanged, true);
            app.inAirDensity.Value = 1.225;
            app.MeanAerodynamicChordLabel_2 = uilabel(app.FlightConditions);
            app.MeanAerodynamicChordLabel_2.Text = 'Velocity (m/s)';
            app.inFreeVelocity = uieditfield(app.FlightConditions, 'numeric');
            app.inFreeVelocity.ValueChangedFcn = createCallbackFcn(app, @inFreeVelocityValueChanged, true);
            app.AspectRatioLabel_2 = uilabel(app.FlightConditions);
            app.AspectRatioLabel_2.Text = 'Dynamic Pressure (Pa)';
            app.inDynamicPressure = uieditfield(app.FlightConditions, 'numeric');
            app.inDynamicPressure.Editable = 'off';
            app.FlightConditionsLabel = uilabel(app.FlightConditions);
            app.FlightConditionsLabel.Text = 'Flight Conditions';
            app.AerodynamicCoeficients = uipanel(app.PerformanceTab);
            app.WingspanLabel_4 = uilabel(app.AerodynamicCoeficients);
            app.WingspanLabel_4.Text = 'CL';
            app.inCL = uieditfield(app.AerodynamicCoeficients, 'numeric');
            app.inCL.ValueChangedFcn = createCallbackFcn(app, @inCLValueChanged, true);
            app.MeanAerodynamicChordLabel_3 = uilabel(app.AerodynamicCoeficients);
            app.MeanAerodynamicChordLabel_3.Text = 'CD0';
            app.inCD0 = uieditfield(app.AerodynamicCoeficients, 'numeric');
            app.inCD0.ValueChangedFcn = createCallbackFcn(app, @inCD0ValueChanged, true);
            app.AspectRatioLabel_3 = uilabel(app.AerodynamicCoeficients);
            app.AspectRatioLabel_3.Text = 'Oswald';
            app.inOswald = uieditfield(app.AerodynamicCoeficients, 'numeric');
            app.inOswald.ValueChangedFcn = createCallbackFcn(app, @inOswaldValueChanged, true);
            app.inOswald.Value = 0.9;
            app.AerodynamicCoeficientsLabel = uilabel(app.AerodynamicCoeficients);
            app.AerodynamicCoeficientsLabel.Text = 'Aerodynamic Coeficients';
            app.AspectRatioLabel_4 = uilabel(app.AerodynamicCoeficients);
            app.AspectRatioLabel_4.Text = 'CD';
            app.inCD = uieditfield(app.AerodynamicCoeficients, 'numeric');
            app.inCD.Editable = 'off';
            app.AspectRatioLabel_5 = uilabel(app.AerodynamicCoeficients);
            app.AspectRatioLabel_5.Text = 'k';
            app.inK = uieditfield(app.AerodynamicCoeficients, 'numeric');
            app.inK.Editable = 'off';
            app.FuelParameters = uipanel(app.PerformanceTab);
            app.WingspanLabel_5 = uilabel(app.FuelParameters);
            app.WingspanLabel_5.Text = 'Fuel Mass (kg)';
            app.inFuelMass = uieditfield(app.FuelParameters, 'numeric');
            app.inFuelMass.ValueChangedFcn = createCallbackFcn(app, @inFuelMassValueChanged, true);
            app.MeanAerodynamicChordLabel_4 = uilabel(app.FuelParameters);
            app.MeanAerodynamicChordLabel_4.Text = 'TSFC (kg/(Ns))';
            app.inTSFC = uieditfield(app.FuelParameters, 'numeric');
            app.inTSFC.ValueChangedFcn = createCallbackFcn(app, @inTSFCValueChanged, true);
            app.FuelParametersLabel = uilabel(app.FuelParameters);
            app.FuelParametersLabel.Text = 'Fuel Parameters';
            app.CalculatedParameters = uipanel(app.PerformanceTab);
            app.WingspanLabel_6 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_6.Text = 'Lift (N)';
            app.inLift = uieditfield(app.CalculatedParameters, 'numeric');
            app.inLift.ValueChangedFcn = createCallbackFcn(app, @inLiftValueChanged, true);
            app.inLift.Editable = 'off';
            app.MeanAerodynamicChordLabel_5 = uilabel(app.CalculatedParameters);
            app.MeanAerodynamicChordLabel_5.Text = 'Drag (N)';
            app.inDrag = uieditfield(app.CalculatedParameters, 'numeric');
            app.inDrag.ValueChangedFcn = createCallbackFcn(app, @inDragValueChanged, true);
            app.inDrag.Editable = 'off';
            app.CalculatedParametersforCruisePerformanceLabel = uilabel(app.CalculatedParameters);
            app.CalculatedParametersforCruisePerformanceLabel.Text = 'Calculated Parameters for Cruise Performance';
            app.WingspanLabel_7 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_7.Text = 'Initial Mass (kg)';
            app.inInitialMass = uieditfield(app.CalculatedParameters, 'numeric');
            app.inInitialMass.ValueChangedFcn = createCallbackFcn(app, @inInitialMassValueChanged, true);
            app.inInitialMass.Editable = 'off';
            app.WingspanLabel_8 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_8.Text = 'Final Mass (kg)';
            app.inFinalMass = uieditfield(app.CalculatedParameters, 'numeric');
            app.inFinalMass.ValueChangedFcn = createCallbackFcn(app, @inFinalMassValueChanged, true);
            app.inFinalMass.Editable = 'off';
            app.WingspanLabel_9 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_9.Text = 'Lift to Drag Ratio';
            app.inLD = uieditfield(app.CalculatedParameters, 'numeric');
            app.inLD.ValueChangedFcn = createCallbackFcn(app, @inLDValueChanged, true);
            app.inLD.Editable = 'off';
            app.WingspanLabel_10 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_10.Text = 'Thrust to Weight';
            app.inThrustToWeight = uieditfield(app.CalculatedParameters, 'numeric');
            app.inThrustToWeight.ValueChangedFcn = createCallbackFcn(app, @inThrustToWeightValueChanged, true);
            app.inThrustToWeight.Editable = 'off';
            app.WingspanLabel_11 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_11.Text = 'W/S (Pa)';
            app.inWingLoading = uieditfield(app.CalculatedParameters, 'numeric');
            app.inWingLoading.ValueChangedFcn = createCallbackFcn(app, @inWingLoadingValueChanged, true);
            app.inWingLoading.Editable = 'off';
            app.WingspanLabel_12 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_12.Text = 'Max L/D';
            app.inMaxLD = uieditfield(app.CalculatedParameters, 'numeric');
            app.inMaxLD.ValueChangedFcn = createCallbackFcn(app, @inMaxLDValueChanged, true);
            app.inMaxLD.Editable = 'off';
            app.WingspanLabel_13 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_13.Text = 'Weight (N)';
            app.inWeight = uieditfield(app.CalculatedParameters, 'numeric');
            app.inWeight.ValueChangedFcn = createCallbackFcn(app, @inWeightValueChanged, true);
            app.inWeight.Editable = 'off';
            app.WingspanLabel_14 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_14.Text = 'Thrust (N)';
            app.inThrust = uieditfield(app.CalculatedParameters, 'numeric');
            app.inThrust.ValueChangedFcn = createCallbackFcn(app, @inThrustValueChanged, true);
            app.inThrust.Editable = 'off';
            app.OverideParametersPanel = uipanel(app.CalculatedParameters);
            app.OverideParametersPanel.Title = 'Overide Parameters';
            app.OverideParametersSwitch = uiswitch(app.OverideParametersPanel, 'slider');
            app.OverideParametersSwitch.ValueChangedFcn = createCallbackFcn(app, @OverideParametersSwitchValueChanged, true);
            app.OverideLabel = uilabel(app.CalculatedParameters);
            app.OverideLabel.Text = {'Parameters are calculated using the properties on the'; 'left. Use this option to enable parameter overiding of the'; 'non-editable parameters above. Note that reverting this '; 'option will recalculate the paramters!'};
            app.WingspanLabel_15 = uilabel(app.CalculatedParameters);
            app.WingspanLabel_15.Text = 'Prop. Efficiency';
            app.inPropEfficiency = uieditfield(app.CalculatedParameters, 'numeric');
            app.inPropEfficiency.ValueChangedFcn = createCallbackFcn(app, @inPropEfficiencyValueChanged, true);
            app.inPropEfficiency.Value = 0.7;
            app.CruisePerformance = uipanel(app.PerformanceTab);
            app.WingspanLabel_16 = uilabel(app.CruisePerformance);
            app.WingspanLabel_16.Text = 'Jet Range (m)';
            app.inJetRange = uieditfield(app.CruisePerformance, 'numeric');
            app.inJetRange.Editable = 'off';
            app.MeanAerodynamicChordLabel_6 = uilabel(app.CruisePerformance);
            app.MeanAerodynamicChordLabel_6.Text = 'Propeller Range (m)';
            app.inPropRange = uieditfield(app.CruisePerformance, 'numeric');
            app.inPropRange.Editable = 'off';
            app.AspectRatioLabel_6 = uilabel(app.CruisePerformance);
            app.AspectRatioLabel_6.Text = 'Jet Endurance (hr)';
            app.inJetEndurance = uieditfield(app.CruisePerformance, 'numeric');
            app.inJetEndurance.Editable = 'off';
            app.WingspanLabel_17 = uilabel(app.CruisePerformance);
            app.WingspanLabel_17.Text = 'Prop. Endurance (hr)';
            app.inPropEndurance = uieditfield(app.CruisePerformance, 'numeric');
            app.inPropEndurance.Editable = 'off';
            app.CruisePerformanceLabel = uilabel(app.CruisePerformance);
            app.CruisePerformanceLabel.Text = 'Cruise Performance';
            app.ClimbPerformance = uipanel(app.PerformanceTab);
            app.WingspanLabel_18 = uilabel(app.ClimbPerformance);
            app.WingspanLabel_18.Text = '(R/C)max (m/s)';
            app.inJetRCMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inJetRCMax.Editable = 'off';
            app.MeanAerodynamicChordLabel_7 = uilabel(app.ClimbPerformance);
            app.MeanAerodynamicChordLabel_7.Text = 'V(R/C)max (m/s)';
            app.inJetVRCMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inJetVRCMax.Editable = 'off';
            app.AspectRatioLabel_7 = uilabel(app.ClimbPerformance);
            app.AspectRatioLabel_7.Text = 'Theta Max (deg)';
            app.inJetThetaMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inJetThetaMax.Editable = 'off';
            app.WingspanLabel_19 = uilabel(app.ClimbPerformance);
            app.WingspanLabel_19.Text = 'V Theta Max  (m/s)';
            app.inJetVThetaMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inJetVThetaMax.Editable = 'off';
            app.ClimbPerformanceJetLabel = uilabel(app.ClimbPerformance);
            app.ClimbPerformanceJetLabel.Text = 'Climb Performance Jet';
            app.ClimbPerformancePropellerLabel = uilabel(app.ClimbPerformance);
            app.ClimbPerformancePropellerLabel.Text = 'Climb Performance Propeller';
            app.WingspanLabel_20 = uilabel(app.ClimbPerformance);
            app.WingspanLabel_20.Text = 'V Theta Max  (m/s)';
            app.inPropVThetaMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inPropVThetaMax.Editable = 'off';
            app.AspectRatioLabel_8 = uilabel(app.ClimbPerformance);
            app.AspectRatioLabel_8.Text = 'Theta Max (deg)';
            app.inPropThetaMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inPropThetaMax.Editable = 'off';
            app.WingspanLabel_21 = uilabel(app.ClimbPerformance);
            app.WingspanLabel_21.Text = '(R/C)max (m/s)';
            app.inPropRCMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inPropRCMax.Editable = 'off';
            app.WingspanLabel_25 = uilabel(app.ClimbPerformance);
            app.WingspanLabel_25.Text = 'Theta (R/C)max (deg)';
            app.inJetThetaRCMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inJetThetaRCMax.Editable = 'off';
            app.WingspanLabel_26 = uilabel(app.ClimbPerformance);
            app.WingspanLabel_26.Text = 'Theta (R/C)max (deg)';
            app.inPropThetaRCMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inPropThetaRCMax.Editable = 'off';
            app.MeanAerodynamicChordLabel_8 = uilabel(app.ClimbPerformance);
            app.MeanAerodynamicChordLabel_8.Text = 'V(R/C)max (m/s)';
            app.inPropVRCMax = uieditfield(app.ClimbPerformance, 'numeric');
            app.inPropVRCMax.Editable = 'off';
            app.ClimbPerformaceInput = uipanel(app.PerformanceTab);
            app.ClimbPerformanceInputsLabel = uilabel(app.ClimbPerformaceInput);
            app.ClimbPerformanceInputsLabel.Text = 'Climb Performance Inputs';
            app.WingspanLabel_22 = uilabel(app.ClimbPerformaceInput);
            app.WingspanLabel_22.Text = 'Thrust to Weight';
            app.inClimbThrustToWeight = uieditfield(app.ClimbPerformaceInput, 'numeric');
            app.inClimbThrustToWeight.ValueChangedFcn = createCallbackFcn(app, @inClimbThrustToWeightValueChanged, true);
            app.WingspanLabel_23 = uilabel(app.ClimbPerformaceInput);
            app.WingspanLabel_23.Text = 'Wing Loading (Pa)';
            app.inClimbWingLoading = uieditfield(app.ClimbPerformaceInput, 'numeric');
            app.inClimbWingLoading.ValueChangedFcn = createCallbackFcn(app, @inClimbWingLoadingValueChanged, true);
            app.WingspanLabel_24 = uilabel(app.ClimbPerformaceInput);
            app.WingspanLabel_24.Text = 'Weight (N)';
            app.inClimbWeight = uieditfield(app.ClimbPerformaceInput, 'numeric');
            app.inClimbWeight.ValueChangedFcn = createCallbackFcn(app, @inClimbWeightValueChanged, true);
            app.NoteLabel = uilabel(app.ClimbPerformaceInput);
            app.NoteLabel.Text = {'CD0, k, (L/D)max, denisty, and propeller '; 'efficiency are taken from the inputs above.'};
            app.WingspanLabel_27 = uilabel(app.ClimbPerformaceInput);
            app.WingspanLabel_27.Text = 'Prop. Power (Nm/s)';
            app.inPropPower = uieditfield(app.ClimbPerformaceInput, 'numeric');
            app.inPropPower.ValueChangedFcn = createCallbackFcn(app, @inPropPowerValueChanged, true);
            app.GlidePerformance = uipanel(app.PerformanceTab);
            app.GlidePerformanceLabel = uilabel(app.GlidePerformance);
            app.GlidePerformanceLabel.Text = 'Glide Performance';
            app.WingspanLabel_28 = uilabel(app.GlidePerformance);
            app.WingspanLabel_28.Text = 'Min Glide Angle (deg)';
            app.inMinGlideAngle = uieditfield(app.GlidePerformance, 'numeric');
            app.inMinGlideAngle.Editable = 'off';
            app.WingspanLabel_29 = uilabel(app.GlidePerformance);
            app.WingspanLabel_29.Text = 'Initial Height (m)';
            app.inInitHeight = uieditfield(app.GlidePerformance, 'numeric');
            app.inInitHeight.ValueChangedFcn = createCallbackFcn(app, @inInitHeightValueChanged, true);
            app.WingspanLabel_30 = uilabel(app.GlidePerformance);
            app.WingspanLabel_30.Text = 'Min Sink Speed (m/s)';
            app.inDescentRate = uieditfield(app.GlidePerformance, 'numeric');
            app.inDescentRate.Editable = 'off';
            app.WingspanLabel_31 = uilabel(app.GlidePerformance);
            app.WingspanLabel_31.Text = 'Gliding Time (hr)';
            app.inGlidingTime = uieditfield(app.GlidePerformance, 'numeric');
            app.inGlidingTime.Editable = 'off';
            app.HelpPanel = uipanel(app.PerformanceTab);
            app.HelpPanel.Title = 'Help';
            app.helpLabel = uilabel(app.HelpPanel);
            app.helpLabel.Text = {'Input parameters in Aircraft Geometry, Aerodynamic Coeficient, Flight '; 'Condition, and Fuel Parameter panels to calculate cruise performance. Note '; 'that the Glide Performance panel will also use these inputs. To calculate climb'; 'performance you must specify new climb performance inputs in the respective'; 'panel. Propeller efficiency is used too. Note that some values from the above '; 'panels will be used in the climb calculations. If at any point you need to overide'; 'a calculated parameter value, use the overide switch. Doing this will disable the'; 'auto-calculate feature so make sure to recalculate necessaryu parameter.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = performance_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end